classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VstupyMenu                      matlab.ui.container.Menu
        CasyMenu                        matlab.ui.container.Menu
        XLSX_suborMenu                  matlab.ui.container.Menu
        CSV_suborMenu                   matlab.ui.container.Menu
        MAT_suborMenu                   matlab.ui.container.Menu
        PaketyzMenu                     matlab.ui.container.Menu
        XLSX_paketyMenu                 matlab.ui.container.Menu
        CSV_paketyMenu                  matlab.ui.container.Menu
        MAT_paketyMenu                  matlab.ui.container.Menu
        PCAPsuborMenu                   matlab.ui.container.Menu
        FunkcionalityMenu               matlab.ui.container.Menu
        UpravaVstupuMenu                matlab.ui.container.Menu
        KombinovanieMetodMenu           matlab.ui.container.Menu
        Funkcie2DMenu                   matlab.ui.container.Menu
        Menu2DPriemer                   matlab.ui.container.Menu
        Menu2DOdchylka                  matlab.ui.container.Menu
        Menu2DVariabilnost              matlab.ui.container.Menu
        Menu2DSikmost                   matlab.ui.container.Menu
        Menu2DSpicatost                 matlab.ui.container.Menu
        Menu2DHurst                     matlab.ui.container.Menu
        Menu2DHurst_RS                  matlab.ui.container.Menu
        Menu2DHurst_DFA                 matlab.ui.container.Menu
        Menu2DHurst_Abs                 matlab.ui.container.Menu
        Menu2DHurst_Default             matlab.ui.container.Menu
        Menu2DEntropia                  matlab.ui.container.Menu
        Menu2DEuklidovskaVelkost        matlab.ui.container.Menu
        Menu2DAutoregresia              matlab.ui.container.Menu
        Menu2DKorelacia                 matlab.ui.container.Menu
        Menu2DDivergencia               matlab.ui.container.Menu
        Menu2D_PCA                      matlab.ui.container.Menu
        Funkcie3DMenu                   matlab.ui.container.Menu
        Menu3DPriemer                   matlab.ui.container.Menu
        Menu3DOdchylka                  matlab.ui.container.Menu
        Menu3DVariabilnost              matlab.ui.container.Menu
        Menu3DSikmost                   matlab.ui.container.Menu
        Menu3DSpicatost                 matlab.ui.container.Menu
        Menu3DHurst                     matlab.ui.container.Menu
        Menu3DHurst_RS                  matlab.ui.container.Menu
        Menu3DHurst_DFA                 matlab.ui.container.Menu
        Menu3DHurst_Abs                 matlab.ui.container.Menu
        Menu3DHurst_Default             matlab.ui.container.Menu
        Menu3DEntropia                  matlab.ui.container.Menu
        Menu3DEuklidovskaVelkost        matlab.ui.container.Menu
        Menu3DAutoregresia              matlab.ui.container.Menu
        Menu3DKorelacia                 matlab.ui.container.Menu
        Menu3DDivergencia               matlab.ui.container.Menu
        PredikciaMenu                   matlab.ui.container.Menu
        MenuPredikcie                   matlab.ui.container.Menu
        Panel_2DFunkcie                 matlab.ui.container.Panel
        Button2DUloz_FigureData         matlab.ui.control.Button
        Button2DUloz_FigureParameter    matlab.ui.control.Button
        ButtonResetovat2D               matlab.ui.control.Button
        EditField2D_Posun               matlab.ui.control.NumericEditField
        PosunLabel2D_Posun              matlab.ui.control.Label
        LabelHodnota2D_Posun            matlab.ui.control.Label
        LabelHodnotaMatice              matlab.ui.control.Label
        EditField2D_Riadky              matlab.ui.control.NumericEditField
        Label_PocetRiadkov              matlab.ui.control.Label
        EditFieldVynasob2D              matlab.ui.control.NumericEditField
        ButtonUlozParameter2D           matlab.ui.control.Button
        ButtonVynasobParameter2D        matlab.ui.control.Button
        ButtonVypocitat2D               matlab.ui.control.Button
        LabelHodnotaOkna2D              matlab.ui.control.Label
        EditField2D_Hodnota             matlab.ui.control.NumericEditField
        HodnotaLabel                    matlab.ui.control.Label
        ButtonNacitatVstup2D            matlab.ui.control.Button
        Label2DNacitanySubor            matlab.ui.control.Label
        Label2DNacitanySuborInfo        matlab.ui.control.Label
        Button2DFunkcie_Ukoncit         matlab.ui.control.Button
        UIAxes2DFunkciaView             matlab.ui.control.UIAxes
        UIAxes2DFunkcia                 matlab.ui.control.UIAxes
        Panel_Predikcia                 matlab.ui.container.Panel
        PanelPredikcia_NacitajVstupneData  matlab.ui.container.Panel
        EditFieldPanelPredikcia_CasUtoku  matlab.ui.control.NumericEditField
        ZadajastokuEditFieldLabel       matlab.ui.control.Label
        ButtonPanelPredikcia_PotvrdCasUtoku  matlab.ui.control.Button
        LabelPanelPredikcia_Vstup       matlab.ui.control.Label
        LabelPanelPredikcia_NacitanySubor  matlab.ui.control.Label
        ButtonPanelPredikcia_Vstup      matlab.ui.control.Button
        UIAxesPanelPredikcia_Parameter  matlab.ui.control.UIAxes
        EditFieldPredikcia_StupenPolynomu  matlab.ui.control.NumericEditField
        StupepolynmuEditFieldLabel      matlab.ui.control.Label
        EditFieldPredikcia_Do           matlab.ui.control.NumericEditField
        DoEditFieldLabel                matlab.ui.control.Label
        EditFieldPredikcia_Od           matlab.ui.control.NumericEditField
        OdEditFieldLabel                matlab.ui.control.Label
        EditFieldPredikcia_sigmy        matlab.ui.control.NumericEditField
        ZvotepoetsigiemEditFieldLabel   matlab.ui.control.Label
        DropDownPredikcia_Vyhladenie    matlab.ui.control.DropDown
        ZvotevyhladenieDropDownLabel    matlab.ui.control.Label
        DropDownPredikcia_Tunel         matlab.ui.control.DropDown
        ZvoteparameterLabel             matlab.ui.control.Label
        EditField_B                     matlab.ui.control.NumericEditField
        EditField_Beta                  matlab.ui.control.NumericEditField
        EditField_Alfa                  matlab.ui.control.NumericEditField
        Label_B                         matlab.ui.control.Label
        Label_Beta                      matlab.ui.control.Label
        Label_Alfa                      matlab.ui.control.Label
        LabelPredikcia_PocetVysledok    matlab.ui.control.Label
        LabelPredikcia_DlzkaVysledok    matlab.ui.control.Label
        LabelPredikcia_RozpoznanieVysledok  matlab.ui.control.Label
        LabelPredikcia_PocetPoplachu    matlab.ui.control.Label
        LabelPredikcia_DlzkaPoplachu    matlab.ui.control.Label
        LabelPredikcia_Rozpoznanie      matlab.ui.control.Label
        EditFieldPredikcia_Rozsah       matlab.ui.control.NumericEditField
        CheckBoxPredikcia_Rozsah        matlab.ui.control.CheckBox
        ButtonPredikcia_Ukoncit         matlab.ui.control.Button
        LabelPredikcia_NacitanySubor    matlab.ui.control.Label
        LabelPredikcia_Vstup            matlab.ui.control.Label
        ButtonPredikcia_NacitatVstup    matlab.ui.control.Button
        ButtonPredikcia_Vypocitat       matlab.ui.control.Button
        EditFieldPredikcia_NastavOkno   matlab.ui.control.NumericEditField
        NastavprediknoknoEditFieldLabel  matlab.ui.control.Label
        UIAxes_Predikcia_HH             matlab.ui.control.UIAxes
        UIAxes_Predikcia_DH             matlab.ui.control.UIAxes
        UIAxes_Predikcia_predikcia      matlab.ui.control.UIAxes
        UIAxes_Predikcia_tunel          matlab.ui.control.UIAxes
        Panel_KombinovanieMetod         matlab.ui.container.Panel
        ButtonKombinovanie_UlozFigure   matlab.ui.control.Button
        EditFieldKombinovanie_Vynasob   matlab.ui.control.NumericEditField
        VynsobslomEditFieldLabel        matlab.ui.control.Label
        EditField_Kombinovanie_okno     matlab.ui.control.NumericEditField
        ZadajtehodnotuoknaprevpoetEditFieldLabel  matlab.ui.control.Label
        ButtonKombinovanie_ClearGraf    matlab.ui.control.Button
        ButtonKombinovanie_Resetovat    matlab.ui.control.Button
        ButtonKombinovanie_Priemer      matlab.ui.control.Button
        ButtonKombinovanie_Vynasob_priemer  matlab.ui.control.Button
        ButtonKombinovanie_Odchylka     matlab.ui.control.Button
        ButtonKombinovanie_Vynasob_odchylka  matlab.ui.control.Button
        ButtonKombinovanie_Variabilnost  matlab.ui.control.Button
        ButtonKombinovanie_Vynasob_variabilnost  matlab.ui.control.Button
        ButtonKombinovanie_Sikmost      matlab.ui.control.Button
        ButtonKombinovanie_Vynasob_sikmost  matlab.ui.control.Button
        ButtonKombinovanie_Spicatost    matlab.ui.control.Button
        ButtonKombinovanie_Vynasob_spicatost  matlab.ui.control.Button
        ButtonKombinovanie_Hurst        matlab.ui.control.Button
        ButtonKombinovanie_Vynasob_hurst  matlab.ui.control.Button
        ButtonKombinovanie_Entropia     matlab.ui.control.Button
        ButtonKombinovanie_Vynasob_entropia  matlab.ui.control.Button
        ButtonKombinovanie_EuklidovskaVelkost  matlab.ui.control.Button
        ButtonKombinovanie_Vynasob_EuklidovskaVelkost  matlab.ui.control.Button
        ButtonKombinovanie_Autoregresia  matlab.ui.control.Button
        ButtonKombinovanie_Vynasob_autoregresia  matlab.ui.control.Button
        ButtonKombinovanie_Korelacia    matlab.ui.control.Button
        ButtonKombinovanie_Vynasob_korelacia  matlab.ui.control.Button
        ButtonKombinovanie_Divergencia  matlab.ui.control.Button
        ButtonKombinovanie_Vynasob_divergencia  matlab.ui.control.Button
        LabelKombinovanie               matlab.ui.control.Label
        LabelKombinovanie_NacitanySubor  matlab.ui.control.Label
        LabelKombinovanie_Vstup         matlab.ui.control.Label
        ButtonKombinovanie_NacitatVstup  matlab.ui.control.Button
        ButtonKombinovanie_Ukoncit      matlab.ui.control.Button
        UIAxesKombinovanie_Data         matlab.ui.control.UIAxes
        UIAxesKombinovanie              matlab.ui.control.UIAxes
        Panel_3DFunkcie                 matlab.ui.container.Panel
        Button3DFunkcie_Resetovat       matlab.ui.control.Button
        Button3DUloz_FigureParameter    matlab.ui.control.Button
        Button3DUloz_FigureData         matlab.ui.control.Button
        ButtonVynasobParameter3D        matlab.ui.control.Button
        EditFieldVynasob3D              matlab.ui.control.NumericEditField
        ButtonVypocitat3D               matlab.ui.control.Button
        EditField_Hodnota5              matlab.ui.control.NumericEditField
        Hodnota5EditFieldLabel          matlab.ui.control.Label
        EditField_Hodnota4              matlab.ui.control.NumericEditField
        Hodnota4EditFieldLabel          matlab.ui.control.Label
        EditField_Hodnota3              matlab.ui.control.NumericEditField
        Hodnota3EditFieldLabel          matlab.ui.control.Label
        EditField_Hodnota2              matlab.ui.control.NumericEditField
        Hodnota2EditFieldLabel          matlab.ui.control.Label
        Zadajtehodnotyprevpoetmin1max5Label  matlab.ui.control.Label
        EditField_Hodnota1              matlab.ui.control.NumericEditField
        Hodnota1EditFieldLabel          matlab.ui.control.Label
        ButtonNacitatVstup3D            matlab.ui.control.Button
        LabelNacitanySubor              matlab.ui.control.Label
        LabelNacitanySuborInfo          matlab.ui.control.Label
        Button3DFunkcie_Ukoncit         matlab.ui.control.Button
        UIAxes3DViewFunkcia             matlab.ui.control.UIAxes
        UIAxes3DFunkcia                 matlab.ui.control.UIAxes
        UlozTimestampButton             matlab.ui.control.Button
        UlozPacketLengthButton          matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        NatansborEditFieldLabel         matlab.ui.control.Label
        UlozPrirastkyCasuButton         matlab.ui.control.Button
        UlozkBPrirastkyButton           matlab.ui.control.Button
        Panel_UpravaVstupu              matlab.ui.container.Panel
        NacitatVstupButton              matlab.ui.control.Button
        UkonciButton                    matlab.ui.control.Button
        Panel_StrihajPo                 matlab.ui.control.NumericEditField
        Panel_dvojbodka                 matlab.ui.control.Label
        Panel_StrihajOd                 matlab.ui.control.NumericEditField
        VystrihniButton                 matlab.ui.control.Button
        Panel_UIAxes                    matlab.ui.control.UIAxes
        PrepocitajButton                matlab.ui.control.Button
        ButtonUlozFigure_a              matlab.ui.control.Button
        ButtonZobrazHistogram_a         matlab.ui.control.Button
        EditField_Histogram_a           matlab.ui.control.NumericEditField
        ButtonZobrazHistogram_b         matlab.ui.control.Button
        EditField_Histogram_b           matlab.ui.control.NumericEditField
        ButtonUlozFigure_b              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        EditField_NacitanySubor         matlab.ui.control.EditField
        EditField_LengthOfData          matlab.ui.control.EditField
        VekosnatanchdtLabel             matlab.ui.control.Label
        NovvekospresamplewindowLabel    matlab.ui.control.Label
        EditField_MeanOfTimes           matlab.ui.control.EditField
        EditField_MeanPackets           matlab.ui.control.EditField
        EditField_StandardDeviation     matlab.ui.control.EditField
        EditField_StandardDeviation_2   matlab.ui.control.EditField
        EditField_SampleWindow          matlab.ui.control.EditField
        EditField_ChangeSampleWindow    matlab.ui.control.NumericEditField
        tandardnodchlkaLabel_2          matlab.ui.control.Label
        tandardnodchlkaLabel_3          matlab.ui.control.Label
        PriemerLabel_2                  matlab.ui.control.Label
        tandardnodchlkaLabel            matlab.ui.control.Label
        PriemerLabel                    matlab.ui.control.Label
        UIAxes_Prirastky                matlab.ui.control.UIAxes
        UIAxes_KB                       matlab.ui.control.UIAxes
        UIAxes_Te                       matlab.ui.control.UIAxes
        UIAxes_Kumulativ_Pakety         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filename,path;
        timestamp;              % ulozene data s casmi
        packets;                % ulozene data s casmi a paketmi
        packetLength;           % ulozene data s velkostami paketov
        time_figure;            % ulozena figure prirastkov a
        packet_figure;          % ulozena figure prirastkov kB b
        N;                      % velkost PCAP suboru
        te;                     % medzery
        T2;                     % vektor nacitanych dat (casy, pakety)
        x,y;
        scaleFrom;              % os od
        scaleTo;                % os do
        numberOfRows;           % pocet riadkov nacitanych dat
        meanOfTimes;            
        stdOfTimes;   
        meanOfPackets;
        stdOfPackets;
        inputData;              % nazov suboru, ktory sa vlozil ako vstup

        importedDataType        % 0 = defaultny subor, nie je to tabulka, 1 = casy sa budu strihat(tabulka), 2 = pakety sa budu strihat(tabulka)
        importedData;           % data pre strihanie
        importedTime;           % casy, ktore sa budu strihat (jeden stlpec)
        importedPackets;        % pakety, ktore sa budu strihat (dva stlpce)
        newData;                % data, ktore nam vznikli po uprave v paneli pre upravovanie importu

        dataline;               % vykresluje hodnoty pri prepocitavani, aby uzivatel vedel kolko % je nacitanych
        
        okno;
        what_value;             % hodnota ktora urci ake okno pouzit na vypocet
                                % app.what_value == 1 -> berieme okno z
                                % app.UIFigure = EditField_ChangeSampleWindow.Value

        what_time_value;        % hodnota: z akeho suboru sa loadnu casy
                                % app.what_time_value == 1 -> XLSX subor
                                % app.what_time_value == 2 -> CSV subor
                                % app.what_time_value == 3 -> MAT subor

        what_pcap_value;        % hodnota ci sa data nacitali z pcap suboru
                                % app.what_pcap_value == 1 -> je to pcap subor
                                % app.what_pcap_value == 0 -> nie je to pcap subor
        
        what_pakets_value;      % hodnota: z akeho suboru sa nacitali pakety
                                % app.what_pakets_value  == 1 -> XLSX subor
                                % app.what_pakets_value  == 2 -> CSV subor
                                % app.what_pakets_value  == 3 -> MAT subor

        % sloter parameters
        d;                      % timestamp variable
        val;                    % d z GUI sa sem ulozi
        a;                      % prirastky casu
        b;                      % prirastky paketov
        T,I,P;                  % pomocne parametre

        filenameFunctions;
        pathFunctions;
        inputDataForParameter;
        func_param;             % 1 = priemer, 2 = odchylka, 3 = variabilnost,
                                % 4 = sikmost, 5 = spicatost, 6 = hurst,
                                % 7 = entropia, 8 = euklidovska velkost, 9 = autoregresia, 
                                % 10 = korelacia, 11 = divergencia, 12 = PCA

        hurst_DFA = 0; 
        hurst_Abs = 0;
        hurst_RS = 0;  
        hurst_Default = 0;  
        
        % ak sa rata parameter, sem sa ulozia hodnoty podla ratanej metody
        param_mean;
        param_std;
        param_variability;
        param_skewness;
        param_sharpness;
        param_hurst;
        param_entropy;
        param_ecs;
        param_autoregression;
        param_correlation;
        param_divergence;
        param_PCA;

        param_v;                % v nej su ulozene vypocitane hodnoty podla zvolenej 2D funkcie
        param3D_v;              % v nej su ulozene vypocitane hodnoty podla zvolenej 3D funkcie
        method1_2;              % 1 = jednoparametricka, 2 = dvojparametricka
        typeOfMethod;           % 1 = 2D, 2 = 3D, 3 = Testovanie kombinacii metod, 4 = Predikcny tunel
        new_parameter;          % sem sa ulozia hodnoty parametra pri kombinacii metod, aby bol spravne vykresleny na osi x

        error_message = {'Ups, niečo sa pokazilo...Zopakujte operáciu alebo kontaktuje svojho administrátora.'};


        %///////////////////PARAMETRE PREDIKCIA////////////////////

        data_predikcia; % tu su nacitane vstupne data pre predikciu
        vyhladene_data; % tu su data ktore presli procesom vyhladenia
        tunel;     % ulozi sa do nej navratova hodnota z vypoctu tunela - tunel(1,:) - horna hranica, tunel(2,:) - dolna hranica, tunel(3,:) - aproximacia
        predikcna_netoda = 1;

        % skalovanie vykreslenia
        od = 1;
        do = 100000;    % pri kazdom utoku sa nastavi na max dlzku

        utok;
        casUtoku;
        vyhladenie = 0;

        % rozne parametre pre vyhladenia
        alfa = 0.05;
        beta = 0.8;
        bb = 0.5;

        % od ktore po ktory ts sa robi tunel...v tomto pripade  sa nikde
        % nemeni
        zaciatok = 1;   
        vystup = 100000;

        % rozne nastavenia pri metodach, nemeni sa v podstate nikde
        dlzkaPredikcnehoOkna = 100;      % okno z ktoreho sa predikuje
        pocetPredikovanych = 10;     % kolko budeme predikovat

        sigma = 2;      % sirka intervalu
        polynom = 2;        % stupen polynomu pri regresnom polynome
        kalibracia = 400;   % pocet ts na natrenovanie pri ar(m,n)

        pocesFalosnychHlaseni;
        dlzkaFalosnychHlaseni;
        casRozpoznania;
        centrHore;
        centrDole;


    end
    
    properties (Access = public)

    end  
    
    methods (Access = public)       

        function Sloter_function(app, ~)

            if app.what_value == 1
                app.val = app.EditField_ChangeSampleWindow.Value;
                app.EditField_SampleWindow.Value = string(app.val);                
            end

            if app.what_value == 2
                app.val = app.EditFieldDetail_Okno.Value;
            end
            
            
            %if app.what_time_value == 1 || app.what_time_value == 2 || app.what_time_value == 3
            if all(app.what_time_value == 1)||all(app.what_time_value == 2)||all(app.what_time_value == 3)
                app.Sloter_bez_paketov_function;
            else
                datalineSloter = uiprogressdlg(app.UIFigure,'Title','Slotovanie','Icon','membrane.png', ...
                    'Interpreter','html','Cancelable','on','CancelText','Cancel');
                datalineSloter.Message = 'Prebieha slotovanie vašich dát...';                

                app.d = app.val; %timeslot
                sloter_value = 1;
                % ziskame si prirastky a a prirastky b pomocou funkcie
                % sloter vdaka externemu suboru sloter.m a ulozime si ich
                % do tabulky sloter_values odkial ich potom presunieme do
                % jednotlivych pramennych app.a a app.b
                sloter_values = sloter(sloter_value,app.d,app.N,app.te,app.packetLength);
                app.a = table2array(sloter_values(:,1));
                app.b = table2array(sloter_values(:,2));

                close(datalineSloter);
            end
            
        end

        function Sloter_bez_paketov_function(app, ~)

            if app.what_value == 2
                app.val = app.EditFieldDetail_Okno.Value;
            else
                app.val = app.EditField_ChangeSampleWindow.Value;
                app.EditField_SampleWindow.Value = string(app.val);
            end

            datalineSloterBezPaketov = uiprogressdlg(app.UIFigure,'Title','Slotovanie','Icon','membrane.png', ...
                'Interpreter','html','Cancelable','on','CancelText','Cancel');
            datalineSloterBezPaketov.Message = 'Prebieha slotovanie vašich dát...';
            
            % na zaklade sloter_value vieme ci pocitame iba casy alebo pakety
            if all(app.what_time_value == 1)||all(app.what_time_value == 2)||all(app.what_time_value == 3)
                sloter_value = 2;
            end
            
            app.d = app.val; %timeslot
            % ziskame si prirastky 'a' pomocou funkcie
            % sloter vdaka externemu suboru sloter.m a ulozime si ich
            % do tabulky sloter_values odkial ich potom presunieme do
            % jednotlivych pramennych app.a
            sloter_values = sloter(sloter_value,app.d,app.N,app.te,app.timestamp);
            app.a = table2array(sloter_values(:,1));
            
            close(datalineSloterBezPaketov);
         
        end

        
        function Prepocitaj_function(app,~)
            try
                app.dataline = uiprogressdlg(app.UIFigure,'Title','Čakajte prosím','Icon','membrane.png',...
                    'Interpreter','html','Message','Opening the application');
			    app.dataline.Message = 'Začiatok prepočítavania...';
                pause(.5)
            
                % Perform calculations
                %------------------GUI LOADER 33 percent-----------------------
                app.dataline.Value = .33; 
                app.dataline.Message = 'Prebieha načítavanie vašich dát...';
                pause(3)            
    
                if all(app.what_pcap_value == 1)||all(app.what_pakets_value == 1)||all(app.what_pakets_value == 2)||all(app.what_pakets_value == 3)
                    app.timestamp = importdata('TimestampSkuska.mat');
                    app.packetLength = importdata('PacketSkuska.mat');
                else
                    app.timestamp = importdata('TimestampSkuska.mat');
                end
                
    
                % Perform calculations
                %------------------GUI LOADER 67 percent-----------------------
                app.dataline.Value = .67;
                app.dataline.Message = 'Prebieha prepočítavanie...';
                pause(1)
    
                % T2 je vektor s nasimi casmi z PCAP suboru
                % v N je ulozena velkost PCAP suboru
                % v meanOfTimes je priemer casov
                % v stdOfTimes je standardna odchylka
                app.T2 = 0;
                app.T2 = app.timestamp;
                app.N = length(app.T2);
    
                % counting delta from data
                % funkcia diff robi deltu medzi casmi
                app.te = 0;
                app.te = diff(app.T2);
                app.Sloter_function;            
    
                % Finish recalculations
                %------------------GUI FINISHER 100 percent--------------------
                app.dataline.Value = 1;
                app.dataline.Message = 'Koniec prepočítavania';
                pause(2)
            
                % Close dialog box
                close(app.dataline)
    
                app.x = app.T2(:,end);
                app.y = app.T2(:,end);
                app.ButtonZobrazHistogram_a.Text = "Zobraz histogram";
                app.EditField_Histogram_a.Value = 10;
                app.ButtonZobrazHistogram_b.Text = "Zobraz histogram";
                app.EditField_Histogram_b.Value = 10;
                if all(app.what_value == 1)&&all(app.what_pcap_value == 1)
                    % VYKRESLENIE GRAFOV po prepocitani s novym oknom    
                    % ak sme hodnoty nacitali z PCAP suboru zobrazime v
                    % UIAxes_Kumulativ_Pakety velkosti paketov
                    plot(app.UIAxes_Kumulativ_Pakety,app.packetLength);
                    plot(app.UIAxes_Te,app.te);
                    app.packet_figure = plot(app.UIAxes_KB,app.b);
                    app.time_figure = plot(app.UIAxes_Prirastky,app.a);    
                elseif all(app.what_value == 1)&&all(app.what_time_value == 1)
                    plot(app.UIAxes_Kumulativ_Pakety,app.T2);
                    plot(app.UIAxes_Te,app.te);
                    app.time_figure = plot(app.UIAxes_Prirastky,app.a);    
                elseif all(app.what_value == 1)&&all(app.what_pakets_value == 1)
                    % rovnako ak berieme ako vstup pakety v
                    % UIAxes_Kumulativ_Pakety zobrazime velkosti paketov
                    plot(app.UIAxes_Kumulativ_Pakety,app.packetLength);
                    plot(app.UIAxes_Te,app.te);
                    app.packet_figure = plot(app.UIAxes_KB,app.b);
                    app.time_figure = plot(app.UIAxes_Prirastky,app.a);     
                end
                app.okno = 0;
                
            catch
                uialert(app.UIFigure,app.error_message,'Error',...
                'Icon','error');
            end

        end

        function Nacitaj_Tabulku_function(app,~)
            tab = app.UITable;
            if all(app.what_time_value == 1)||all(app.what_time_value == 2)||all(app.what_time_value == 3)
                % vyplnenie tabulky s casmi
                tab.Data = [app.timestamp];
                tab.ColumnFormat = {'long'};
                tab.ColumnName = {'Časy'};
            elseif all(app.what_pakets_value == 1)||all(app.what_pakets_value == 2)||all(app.what_pakets_value == 3)
                tab.Data = [app.timestamp app.packetLength];
                tab.ColumnFormat = {'long' 'long'};
                tab.ColumnName = {'Časy'; 'Pakety'};
            elseif app.what_pcap_value == 1
                % vyplnenie tabulky s casmi a velkostami paketov
                tab.Data = [app.timestamp app.packetLength];
                tab.ColumnFormat = {'long' 'long'};
                tab.ColumnName = {'Časy'; 'Pakety'};
            elseif app.what_pcap_value == 0
                % vyplnenie tabulky s velkostami paketov
                tab.Data = [app.packetLength];
                tab.ColumnFormat = {'long'};
            end
            app.okno = 0;
        end

        function NacitajVstupnySubor_function(app)

            if all(app.what_time_value == 1)||all(app.what_pakets_value == 1)
                [app.filename, app.path]=uigetfile('*.xlsx');
            elseif all(app.what_time_value == 2)||all(app.what_pakets_value == 2)
                [app.filename, app.path]=uigetfile('*.csv');
            elseif all(app.what_time_value == 3)||all(app.what_pakets_value == 3)
                [app.filename, app.path]=uigetfile('*.mat');
            elseif app.what_pcap_value == 1
                [app.filename, app.path]=uigetfile('*.pcap');
            end

            %if app.filename ~= 0 || app.path ~= 0
            if all(app.filename ~= 0)&&all(app.path ~= 0)
                if (app.what_pcap_value == 1)
                    app.NacitajPCAP_function;
                else
                    app.NacitajCasy_Pakety_function;
                end
            else
                message = {'Nebol načítaný žiadny súbor!'};
                uialert(app.UIFigure,message,'Warning',...
                'Icon','warning');
            end
        end

        function NacitajPCAP_function(app,~)

            app.SetNameOfInputFileWithData_function;

            app.dataline = uiprogressdlg(app.UIFigure,'Title','Čakajte prosím','Icon','membrane.png',...
                'Interpreter','html','Message','Opening the application');
            pause(.5)
        
            % Perform calculations
            %------------------GUI LOADER 33 percent-----------------------
            app.dataline.Value = .33; 
            app.dataline.Message = 'Prebieha načítavanie vašich dát z PCAP súboru...';
            pause(1)

            pcapValues = readPCAP(app.path,app.filename);
            Time = table2array(pcapValues(:,1));
            Packet = table2array(pcapValues(:,2));            
        
            % Perform calculations
            %------------------GUI LOADER 67 percent-----------------------
            app.dataline.Value = .67;
            app.dataline.Message = 'Spracovanie dát do objektov...';
            pause(1)
        
            % Finish calculations
            %------------------GUI FINISHER 100 percent--------------------
            app.dataline.Value = 1;
            app.dataline.Message = 'Koniec';
            pause(1)
        
            % Close dialog box
            close(app.dataline)

            %ulozime si nase vektory: cas a velkost paketov
            save('TimestampSkuska','Time');
            save('PacketSkuska',"Packet");
        
            %naimportujeme si casy a pakety pre rychlejsiu pracu
            %[TimestampFile, path]=uigetfile('*.mat');
            %Timestamp = importdata(fullfile(path, TimestampFile));
            %[PacketsFile, path]=uigetfile('*.mat');
            %PacketLength = importdata(fullfile(path, PacketsFile));
            app.timestamp = importdata('TimestampSkuska.mat');
            app.packetLength = importdata("PacketSkuska.mat");
    
            % T2 je vektor s nasimi casmi z PCAP suboru
            % v N je ulozena velkost PCAP suboru
            % v meanOfTimes je priemer casov
            % v stdOfTimes je standardna odchylka
            app.T2 = app.timestamp;

            app.N = 0;
            app.meanOfTimes = 0;
            app.meanOfPackets = 0;
            app.stdOfTimes = 0;
            app.stdOfPackets = 0;

            app.N = length(app.T2);
            app.meanOfTimes = mean(app.T2);
            app.meanOfPackets = mean(app.packetLength);
            app.stdOfTimes = std(app.T2);
            app.stdOfPackets = std(app.packetLength);

            % counting delta from data
            % funkcia diff robi deltu medzi casmi
            app.te = diff(app.T2);
            medzery = app.te;
            save('Medzery','medzery');

            %Length of data
            app.numberOfRows = app.N;
            %displaying the count of data
            app.EditField_LengthOfData.Value = string(app.numberOfRows);
            app.EditField_MeanOfTimes.Value = string(app.meanOfTimes);
            app.EditField_MeanPackets.Value = string(app.meanOfPackets);
            app.EditField_StandardDeviation.Value = string(app.stdOfTimes);
            app.EditField_StandardDeviation_2.Value = string(app.stdOfPackets);

            % funkcia pre vypocet prirastkov podla velkosti okna z GUI
            app.Sloter_function;
        
            app.x = app.T2(:,end);
            app.y = app.T2(:,end);
            plot(app.UIAxes_Kumulativ_Pakety,app.packetLength);
            app.UIAxes_Kumulativ_Pakety.Title.String = "Veľkosti paketov";
            plot(app.UIAxes_Te,app.te);
            app.packet_figure = plot(app.UIAxes_KB,app.b);
            app.time_figure = plot(app.UIAxes_Prirastky,app.a);

            app.Nacitaj_Tabulku_function;    
        end

        function NacitajCasy_Pakety_function(app, ~)
            
            app.SetNameOfInputFileWithData_function;
            app.d = uiprogressdlg(app.UIFigure,'Title','Čakajte prosím','Icon','membrane.png',...
                'Interpreter','html','Message','Opening the application');
            pause(.5)
        
            % Perform calculations
            % ...
            app.d.Value = .33; 
            app.d.Message = 'Prebieha načítavanie vašich dát...';
            pause(1)

            if all(app.what_pakets_value == 1)||all(app.what_pakets_value == 2)||all(app.what_pakets_value == 3)

                %naimportujeme si [casy a pakety v kB] z .mat, csv aj xlsx suboru
                app.packets = 0;
                % ak 1 alebo 2 potom vstupny subor je csv alebo xlsx
                if all(app.what_pakets_value == 1)||all(app.what_pakets_value == 2)
                    app.packets = readtable(fullfile(app.path, app.filename));
                else
                    % ak 3 potom vstupny subor je .mat subor
                    app.packets = importdata(fullfile(app.path, app.filename));
                end
                
                % z nacitanych dat mame 2 stlpce a tie si rozdelime do premennych
                % Time ako stlpec casov
                Time = table2array(app.packets(:,1));
                % Packet ako stlpec paketov v kB
                Packet = table2array(app.packets(:,2));    
                % ulozime si nase vektory: cas a velkost paketov
                save('TimestampSkuska','Time');
                save('PacketSkuska',"Packet");

                % Perform calculations
                % ...
                app.d.Value = .67;
                app.d.Message = 'Dáta sa spracovávajú...';
                pause(1)

                %naimportujeme si casy a pakety pre rychlejsiu pracu
                app.timestamp = importdata('TimestampSkuska.mat');
                app.packetLength = importdata("PacketSkuska.mat");

                % T2 je vektor s nasimi casmi z mat suboru
                % v N je ulozena velkost mat suboru
                % v meanOfTimes je priemer casov
                % v stdOfTimes je standardna odchylka
                app.T2 = app.timestamp;
    
                app.N = 0;
                app.meanOfTimes = 0;
                app.meanOfPackets = 0;
                app.stdOfTimes = 0;
    
                app.N = length(app.T2);
                app.meanOfTimes = mean(app.T2);
                app.meanOfPackets = mean(app.packetLength);
                app.stdOfTimes = std(app.T2);
                app.stdOfPackets = std(app.packetLength);
    
                % counting delta from data
                % funkcia diff robi deltu medzi casmi
                app.te = diff(app.T2);
    
                %Length of data
                app.numberOfRows = app.N;
                %displaying the count of data
                app.EditField_LengthOfData.Value = string(app.numberOfRows);
                app.EditField_MeanOfTimes.Value = string(app.meanOfTimes);
                app.EditField_MeanPackets.Value = string(app.meanOfPackets);
                app.EditField_StandardDeviation.Value = string(app.stdOfTimes);
                app.EditField_StandardDeviation_2.Value = string(app.stdOfPackets);
    
                % funkcia pre vypocet prirastkov podla velkosti okna z GUI
                app.Sloter_function;
            
                app.x = app.T2(:,end);
                app.y = app.T2(:,end);
                plot(app.UIAxes_Kumulativ_Pakety,app.packetLength);
                app.UIAxes_Kumulativ_Pakety.Title.String = "Veľkosti paketov";
                plot(app.UIAxes_Te,app.te);
                app.packet_figure = plot(app.UIAxes_KB,app.b);
                app.time_figure = plot(app.UIAxes_Prirastky,app.a);  
        
            elseif (app.what_time_value == 3)

                %naimportujeme si [casy] z .mat suboru
                app.timestamp = 0;
                app.T2 = 0;
                app.timestamp = importdata(fullfile(app.path, app.filename));
                Time = app.timestamp;
                app.T2 = app.timestamp;
                save('TimestampSkuska','Time');                
                app.timestamp = importdata('TimestampSkuska.mat');

                % Perform calculations
                % ...
                app.d.Value = .67;
                app.d.Message = 'Dáta sa spracovávajú...';
                pause(1)

                app.N = 0;
                app.meanOfTimes = 0;
                app.stdOfTimes = 0;
    
                app.N = length(app.T2);
                app.meanOfTimes = mean(app.T2);
                app.stdOfTimes = std(app.T2);

                % counting delta from data
                % funkcia diff robi deltu medzi casmi/velkostami paketov
                app.te = diff(app.T2);
    
                %Length of data
                app.numberOfRows = app.N;
                %displaying the count of data
                app.EditField_LengthOfData.Value = string(app.numberOfRows);
                %app.EditField_MeanOfTimes.Value = string(app.meanOfTimes);
                %app.EditField_StandardDeviation.Value = string(app.stdOfTimes);
                app.SetMeanInfoBasedOnLoadedData_function;

                app.Sloter_bez_paketov_function;    

                % vykreslenie grafov pre kumulativ, medzery a 
                % prirastky casov/prirastky paketov lebo mame 
                % app.what_time_value == 3 alebo app.what_pakets_value == 3
                plot(app.UIAxes_Kumulativ_Pakety,app.T2);
                app.UIAxes_Kumulativ_Pakety.Title.String = "Kumulatívne medzery medzi paketmi";
                plot(app.UIAxes_Te,app.te);
                app.time_figure = plot(app.UIAxes_Prirastky,app.a);
                
            else
                %Nacitanie dat (casy) z csv/xlsx
                data = readtable(fullfile(app.path, app.filename));
                app.timestamp = table2array(data);
                app.T2 = app.timestamp;
                Time = app.timestamp;
                save('TimestampSkuska','Time');
                app.timestamp = 0;
                app.timestamp = importdata('TimestampSkuska.mat');

                % Perform calculations
                % ...
                app.d.Value = .67;
                app.d.Message = 'Dáta sa spracovávajú...';
                pause(1)              
            
                % Finish calculations
                % ...
                app.d.Value = 1;
                app.d.Message = 'Koniec';
                pause(1)
            
                % Close dialog box
                close(app.d)
    
                app.VypisDetailyODatach_function;
    
                app.Sloter_bez_paketov_function;
    
                app.x = app.T2(:,end);
                app.y = app.T2(:,end);    
                plot(app.UIAxes_Kumulativ_Pakety,app.T2);
                app.UIAxes_Kumulativ_Pakety.Title.String = "Kumulatívne medzery medzi paketmi";
                plot(app.UIAxes_Te,app.te);
                app.time_figure = plot(app.UIAxes_Prirastky,app.a);
                app.okno = 0;
            end
            app.Nacitaj_Tabulku_function;
        end


        function VypisDetailyODatach_function(app, ~)

            app.N = 0;
            app.meanOfTimes = 0;
            app.stdOfTimes = 0;

            app.N = length(app.T2);
            %mean of times
            app.meanOfTimes = mean(app.T2);
            %std of times
            app.stdOfTimes = std(app.T2);

            %counting delta from data
            app.te = diff(app.T2);

            %Length of data
            app.numberOfRows = 0;
            app.numberOfRows = app.N;
            %displaying the count of data
            app.EditField_LengthOfData.Value = string(app.numberOfRows);
            app.SetMeanInfoBasedOnLoadedData_function;
        end

        function SetNameOfInputFileWithData_function(app)         
            % app.inputData = nazov suboru vlozenych dat
            app.inputData = convertCharsToStrings(app.filename);
            app.EditField_NacitanySubor.Value = string(app.inputData);            
        end

        function SetMeanInfoBasedOnLoadedData_function(app)
            if app.what_time_value ~= 0
                app.EditField_MeanOfTimes.Value = string(app.meanOfTimes);
                app.EditField_StandardDeviation.Value = string(app.stdOfTimes);
            elseif app.what_pakets_value ~= 0
                app.EditField_MeanPackets.Value = string(app.meanOfTimes);
                app.EditField_StandardDeviation_2.Value = string(app.stdOfTimes);
            end
            
        end

        function NacitajVstupneData_function(app)

            [app.filenameFunctions, app.pathFunctions]=uigetfile('*.mat');   

            if all(app.filenameFunctions ~= 0)&&all(app.pathFunctions ~= 0)
                app.pathFunctions = fullfile(app.pathFunctions, app.filenameFunctions);
                app.inputDataForParameter = importdata(app.pathFunctions);
                nacitany3DVstup = convertCharsToStrings(app.pathFunctions);
                if app.typeOfMethod == 1
                    app.Label2DNacitanySubor.Text = string(nacitany3DVstup); % pre 2D metody
                elseif app.typeOfMethod == 2
                    app.LabelNacitanySubor.Text = string(nacitany3DVstup); % pre 3D metody
                elseif app.typeOfMethod == 3
                    app.LabelKombinovanie_NacitanySubor.Text = string(nacitany3DVstup); % pre Kombinovanie metod
                elseif app.typeOfMethod == 4
                    app.LabelPredikcia_NacitanySubor.Text = string(nacitany3DVstup); % pre Predikciu metod
                    app.LabelPanelPredikcia_NacitanySubor.Text = string(nacitany3DVstup);% pre Predikciu metod v paneli
                    app.data_predikcia = app.inputDataForParameter;
                    if ~isempty(app.data_predikcia)
                        plot(app.UIAxesPanelPredikcia_Parameter,app.data_predikcia);
                    else
                        message = {'Neboli načítané žiadne dáta!'};
                        uialert(app.UIFigure,message,'Warning',...
                        'Icon','warning');
                    end
                end                
            else
                message = {'Nebol načítaný žiadny súbor!'};
                uialert(app.UIFigure,message,'Warning',...
                'Icon','warning');
            end
        end

        function Resetovat3DOkno_function(app)
            %app.func_param = 0;
            %app.method1_2 = 0;
            cla(app.UIAxes3DFunkcia);
            cla(app.UIAxes3DViewFunkcia);
            app.EditField_Hodnota1.Value = 512;
            app.EditField_Hodnota2.Value = 1024;
            app.EditField_Hodnota3.Value = 2048;
            app.EditField_Hodnota4.Value = 4096;
            app.EditField_Hodnota5.Value = 6000;
            app.LabelNacitanySubor.Text = 'žiadny';
            %app.pathFunctions = 0;
            %app.filenameFunctions = 0;
            %app.inputDataForParameter = 0;
            %app.UIAxes3DFunkcia.Title.String = 'Title';
            %app.UIAxes3DViewFunkcia.Title.String = 'Title';
            app.EditFieldVynasob3D.Value = 2;
        end

        function Resetovat2DOkno_function(app)
            %app.func_param = 0;
            app.inputDataForParameter = 0;
            %app.method1_2 = 0;
            cla(app.UIAxes2DFunkcia);
            cla(app.UIAxes2DFunkciaView);
            app.EditField2D_Hodnota.Value = 512;
            app.Label2DNacitanySubor.Text = 'žiadny'; 
            %app.UIAxes2DFunkcia.Title.String = 'Title';
            %app.UIAxes2DFunkciaView.Title.String = 'Title';    
            app.EditFieldVynasob2D.Value = 2;      

            app.EditField2D_Posun.Value = 1;
            app.EditField2D_Riadky.Value = 8;
        end

        function StiahniFigure(app, vstup, graf, typ_grafu) 
            try
                % skontrolujeme, ci je nieco na vstupe
                if all(isempty(vstup))||all(vstup == 0)
                    message = {'Nie sú dostupné žiadne dáta pre stiahnutie...'};
                    uialert(app.UIFigure,message,'Warning',...
                    'Icon','warning');
                else
                    ux = graf;
                    fig1 = figure;
                    ax = axes;
    
                    % ak je graf 3D a ideme ho ukladat ako figure musime
                    % definovat 3 rozmerny priestor cez view
                    if typ_grafu == 3
                        grid(ax,"on");
                        ax.View = [1 3];                    
                    end
                    
                    copyobj(ux.Children,ax);
                    [file_for_uiaxes, path_for_uiaxes] = uiputfile('*.fig');
    
                    if all(file_for_uiaxes ~= 0)||all(path_for_uiaxes ~= 0)
                        savefig( fig1, fullfile(path_for_uiaxes,file_for_uiaxes));
                    else
                        message = {'Nevybrali ste priečinok pre uloženie...'};
                        uialert(app.UIFigure,message,'Warning',...
                        'Icon','warning');
                    end
                end
            catch
                uialert(app.UIFigure,app.error_message,'Error',...
                'Icon','error');
            end


        end

        % funkcia sluzi na stiahnutie vstupnych dat alebo uz naslotovanych
        % data v podobe prirastkov
        function StiahniData(app, vstup, typ_dat, warning_message, identifikator)

            try
                if all(isempty(vstup))||all(vstup == 0)
                    message = {'Nie sú dostupné žiadne dáta pre stiahnutie...'};
                    uialert(app.UIFigure,message,'Warning',...
                    'Icon','warning');
                else
                    data_na_stiahnutie = vstup;
                    % uigetdir nam umozni si vybrat folder pre ulozenie nasich dat
                    subor_pre_ulozenie = uigetdir;
                    if subor_pre_ulozenie ~= 0
	                    path_as_string = convertCharsToStrings(subor_pre_ulozenie);
	                    tempName = app.inputData;
	                    tempOkno = string(app.d);
                        % ak je ident. 1 potom ma nazov stiahnuteho suboru aj
                        % informaciu o velkosti pouziteho vypoctoveho okna
                        if identifikator == 1
                            path_as_string = path_as_string + typ_dat + tempName + "_sOknom_" + tempOkno + ".mat";
                        elseif identifikator == 0
                            path_as_string = path_as_string + typ_dat + tempName + ".mat";
                        else
                            path_as_string = path_as_string + typ_dat + app.filenameFunctions + '_' + app.UIAxes2DFunkcia.Title.String + "_sOknom_" + tempOkno + ".mat";
                        end
                        save(path_as_string,'data_na_stiahnutie');	 
                    else
                        message = {warning_message};
                        uialert(app.UIFigure,message,'Warning',...
                        'Icon','warning');
                    end 
                end
            catch
                 uialert(app.UIFigure,app.error_message,'Error',...
                'Icon','error');
            end

        end

        function NastavMenu3D(app, func_param_hodnota, metoda1_2_hodnota, Title_nazov, Title2_nazov)
            app.Resetovat3DOkno_function;
            app.Panel_3DFunkcie.Visible = "on";
            app.func_param = func_param_hodnota;
            app.method1_2 = metoda1_2_hodnota;
            app.UIAxes3DFunkcia.Title.String = Title_nazov;
            app.UIAxes3DViewFunkcia.Title.String = Title2_nazov;
        end

        function NastavMenu2D(app, func_param_hodnota, metoda1_2_hodnota, Title_nazov, Title2_nazov)
            app.Resetovat2DOkno_function;
            app.Panel_2DFunkcie.Visible = "on";
            app.func_param = func_param_hodnota;
            app.method1_2 = metoda1_2_hodnota;
            app.UIAxes2DFunkcia.Title.String = Title_nazov;
            app.UIAxes2DFunkciaView.Title.String = Title2_nazov;
        end

        function VypocitajParameter(app,func_param_hodnota,metoda1_2_hodnota,okno_hodnota, uiaxes_view, uiaxes_parameter,uiaxes_farba)
            try
                Nt_Vstup = app.inputDataForParameter;
                if all(isempty(Nt_Vstup))||all(Nt_Vstup == 0)
                    message = {'Nie sú dostupné žiadne dáta pre výpočet...'};
                    uialert(app.UIFigure,message,'Warning',...
                    'Icon','warning');
                else
                    N_Length_Vstup = length(Nt_Vstup);
                    okno_Vstup_Parameter = okno_hodnota;
                    
                    datalineKombinovanie = uiprogressdlg(app.UIFigure,'Title','Prebieha výpočet',...
                    'Indeterminate','on','Icon','membrane.png','Interpreter','html');
        
                    app.param_v = 0;
                    app.param_v = calculate2D(Nt_Vstup,okno_Vstup_Parameter,func_param_hodnota,metoda1_2_hodnota);
                    app.UlozParameter(func_param_hodnota,app.param_v);
        
                    close(datalineKombinovanie);
                                  
                    tcw1 = linspace(okno_Vstup_Parameter,N_Length_Vstup,N_Length_Vstup-okno_Vstup_Parameter);  
                    tcw2 = linspace(okno_Vstup_Parameter+1,N_Length_Vstup,N_Length_Vstup-(okno_Vstup_Parameter+1));
                    if metoda1_2_hodnota == 1
                        plot(uiaxes_parameter,tcw1,app.param_v,Color=uiaxes_farba);
                    else
                        plot(uiaxes_parameter,tcw2,app.param_v,Color=uiaxes_farba);
                    end
                    plot(uiaxes_view,Nt_Vstup);
                end

            catch
                 uialert(app.UIFigure,app.error_message,'Error',...
                'Icon','error');
            end
        end

        function UlozParameter(app,metoda,parameter)
            if metoda == 1
                app.param_mean = parameter;
            elseif metoda == 2
                app.param_std = parameter;
            elseif metoda == 3
                app.param_variability = parameter;
            elseif metoda == 4
                app.param_skewness = parameter;
            elseif metoda == 5
                app.param_sharpness = parameter;
            elseif metoda == 6
                app.param_hurst = parameter;
            elseif metoda == 7
                app.param_entropy = parameter;
            elseif metoda == 8
                app.param_ecs = parameter;
            elseif metoda == 9
                app.param_autoregression = parameter;
            elseif metoda == 10
                app.param_correlation = parameter;
            elseif metoda == 11
                app.param_divergence = parameter;
            elseif metoda == 12
                app.param_PCA = parameter;
            end
        end

        function new_parameter = shiftParameter(~,parameter,okno)
            new_parameter = [zeros(1,okno),parameter];            
        end


        function Option_vyberVyhladenie(app,event)

            switch app.DropDownPredikcia_Vyhladenie.Value
                case 'Žiadne'
                    app.vyhladenie = 0;
                    app.EditField_Alfa.Visible = "off";
                    app.EditField_Beta.Visible = "off";
                    app.EditField_B.Visible = "off";
                    app.Label_Alfa.Visible = "off";
                    app.Label_Beta.Visible = "off";
                    app.Label_B.Visible = "off";
                case 'Exponenciálne'
                    app.vyhladenie = 1;
                    app.EditField_Alfa.Visible = "on";
                    app.EditField_Beta.Visible = "off";
                    app.EditField_B.Visible = "off";
                    app.Label_Alfa.Visible = "on";
                    app.Label_Beta.Visible = "off";
                    app.Label_B.Visible = "off";
                case 'Holt'
                    app.vyhladenie = 2;
                    app.EditField_Alfa.Visible = "on";
                    app.EditField_Beta.Visible = "on";
                    app.EditField_B.Visible = "on";
                    app.Label_Alfa.Visible = "on";
                    app.Label_Beta.Visible = "on";
                    app.Label_B.Visible = "on";
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: PCAPsuborMenu, UIAxes_Kumulativ_Pakety
        function PCAPfileMenuSelected(app, event)
            app.what_value = 1;
            app.what_pcap_value = 1;
            app.what_time_value = 0;
            app.what_pakets_value = 0;
            app.NacitajVstupnySubor_function;
            
        end

        % Button pushed function: PrepocitajButton
        function PrepocitajButtonPushed(app, event)

            app.what_value = 1;             % toto nam urci ze sme zmackli prepocitaj button na hlavnej casti
            if app.what_pcap_value == 1
                app.what_pcap_value = 1;
                app.what_time_value = 0;
                app.what_pakets_value = 0;
                app.what_value = 1;
            elseif  all(app.what_time_value == 1) || all(app.what_time_value == 2) || all(app.what_time_value == 3)
                app.what_pcap_value = 0;
                app.what_time_value = 1;
                app.what_pakets_value = 0;
                app.what_value = 1;
            elseif all(app.what_pakets_value == 1) || all(app.what_pakets_value == 2) || all(app.what_pakets_value == 3)
                app.what_pakets_value = 1;
                app.what_time_value = 0;
                app.what_pcap_value = 0;
                app.what_value = 1;
            end
            app.Prepocitaj_function;            

        end

        % Menu selected function: XLSX_suborMenu
        function XLSX_suborMenuMenuSelected(app, event)
            app.what_time_value = 1;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_pakets_value = 0;
            app.NacitajVstupnySubor_function;
        end

        % Menu selected function: CSV_suborMenu
        function CSV_suborMenuSelected(app, event)
            app.what_time_value = 2;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_pakets_value = 0;
            app.NacitajVstupnySubor_function;
        end

        % Menu selected function: MAT_suborMenu
        function MAT_suborMenuSelected(app, event)
            app.what_time_value = 3;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_pakets_value = 0;
            app.NacitajVstupnySubor_function;
        end

        % Menu selected function: UpravaVstupuMenu
        function UpravaVstupuMenuMenuSelected(app, event)
            app.Panel_UpravaVstupu.Visible = "on";            
        end

        % Button pushed function: UkonciButton
        function UkonciButtonPushed(app, event)
            app.Panel_UpravaVstupu.Visible = "off";
            cla(app.Panel_UIAxes);
            app.Panel_StrihajOd.Value = 1;
            app.Panel_StrihajPo.Value = 100000;
            app.newData = 0;
        end

        % Button pushed function: UlozkBPrirastkyButton
        function UlozkBPrirastkyButtonPushed(app, event)
           app.StiahniData(app.b, "\prirastky_kB_", 'Nevybrali ste priečinok pre uloženie prírastkov paketov v kB...',1);
        end

        % Button pushed function: UlozPrirastkyCasuButton
        function UlozPrirastkyCasuButtonPushed(app, event)
            app.StiahniData(app.a,"\prirastky_casu_",'Nevybrali ste priečinok pre uloženie prírastkov času...',1);            
        end

        % Button pushed function: NacitatVstupButton
        function NacitatVstupButtonPushed(app, event)
            try
                [app.filename, app.path]=uigetfile('*.mat');
                temp_path = fullfile(app.path, app.filename);
                if all(exist(temp_path ,"file"))&&all(length(temp_path) > 3)
                    app.importedData = importdata(temp_path);


                    if istable(app.importedData) ~= 1    
                    plot(app.Panel_UIAxes,app.importedData);
                    app.Panel_StrihajPo.Value = double(length(app.importedData));
                    app.importedDataType = 0;

                    elseif istable(app.importedData)

                        if width(app.importedData) == 1
                            app.importedTime = table2array(app.importedData(:,1));
                            plot(app.Panel_UIAxes,app.importedTime);
                            app.Panel_StrihajPo.Value = double(length(app.importedTime));
                            app.importedDataType = 1;

                        elseif width(app.importedData) == 2
                            app.importedTime = table2array(app.importedData(:,1));
                            app.importedPackets = table2array(app.importedData(:,2));
                            plot(app.Panel_UIAxes,app.importedPackets);
                            app.Panel_StrihajPo.Value = double(length(app.importedPackets));
                            app.importedDataType = 2;
                        end

                    else
                        message = {'Vložili ste zlý formát súboru alebo sú dáta nesprávneho formátu!'};
                        uialert(app.UIFigure,message,'Warning',...
                        'Icon','warning');
                    end

                else
                    message = {'Súbor sa nenašiel...'};
                    uialert(app.UIFigure,message,'Warning',...
                    'Icon','warning');
                end

            catch
                uialert(app.UIFigure,app.error_message,'Error',...
                'Icon','error');
            end

        end

        % Button pushed function: VystrihniButton
        function VystrihniButtonPushed(app, event)
           
            try
                if all(isempty(app.importedData))||all(app.importedData == 0)
                    message = {'Nie sú dostupné žiadne dáta pre strihanie...'};
	                uialert(app.UIFigure,message,'Warning',...
	                'Icon','warning');
                else
                    app.scaleFrom = app.Panel_StrihajOd.Value;
                    app.scaleTo = app.Panel_StrihajPo.Value;
        
                    if app.importedDataType == 0
                        app.newData = app.importedData(app.scaleFrom:app.scaleTo);
                    elseif app.importedDataType == 1
                        app.newData = app.importedTime(app.scaleFrom:app.scaleTo);
                    % ak vystrihujeme pakety, to znamena ze budeme vzstrihovat dva
                    % stlpce. 1 stlpec casov a 2 stlpec velkosti paketov v kB
                    % preto vytvarame tabulku aby sme boli schopni naraz vystrihnut
                    % oba stlpce
                    else
                        t = table();
                        vystrihnutCasy = app.importedTime(app.scaleFrom:app.scaleTo);
                        vystrihnutPakety = app.importedPackets(app.scaleFrom:app.scaleTo);
                        t = addvars(t,vystrihnutCasy);
                        t = addvars(t,vystrihnutPakety);
                        app.newData = t;
                    end
                   
                    % ideme si ulozit tie nove vystrihnute data
                    vystrihnute_data = app.newData;
                    % uigetdir nam umozni si vybrat folder pre ulozenie
                    priecinok = uigetdir;
                    path_as_string = convertCharsToStrings(priecinok);
                    tempName = convertCharsToStrings(app.filename);
                    path_as_string = path_as_string + "\" + "vystrihnute_data_" + tempName + ".mat";
                    save(path_as_string,'vystrihnute_data');
                end
                
            catch
                uialert(app.UIFigure,app.error_message,'Error',...
                'Icon','error');
            end
           

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            cla(app.UIAxes_KB);
            cla(app.UIAxes_Prirastky);
            cla(app.UIAxes_Te);
            cla(app.UIAxes_Kumulativ_Pakety);
            app.UIAxes_Kumulativ_Pakety.Title.String = "Kumulatívne medzery medzi paketmi";
            %app.HurstEditField_1.Value = '0';

            app.EditField_NacitanySubor.Value = 'žiadny';
            app.EditField_LengthOfData.Value = '0';
            app.EditField_MeanOfTimes.Value = '0';
            app.EditField_MeanPackets.Value = '0';
            app.EditField_StandardDeviation.Value = '0';
            app.EditField_StandardDeviation_2.Value = '0';
            app.EditField_SampleWindow.Value = '3000';
            app.EditField_ChangeSampleWindow.Value = 1;
            app.ButtonZobrazHistogram_a.Text = "Zobraz histogram";
            app.EditField_Histogram_a.Value = 10;
            app.ButtonZobrazHistogram_b.Text = "Zobraz histogram";
            app.EditField_Histogram_b.Value = 10;

            tab = app.UITable;
            tab.Data = [];

        end

        % Button pushed function: UlozPacketLengthButton
        function UlozPacketLengthButtonPushed(app, event)
            % pri ulozeni velkosti paketov spolu ukladame aj stlpec casov
            casy = app.timestamp;
            pakety = app.packetLength;
            t = table();
            t = addvars(t,casy);
            t = addvars(t,pakety);
            
            app.StiahniData(t,"\velkosti_paketov_",'Nevybrali ste priečinok pre uloženie veľkosti paketov...',0);          
        end

        % Button pushed function: UlozTimestampButton
        function UlozTimestampButtonPushed(app, event)
            app.StiahniData(app.timestamp,"\prichody_casov_",'Nevybrali ste priečinok pre uloženie časov...',0);
        end

        % Menu selected function: XLSX_paketyMenu
        function XLSX_paketyMenuSelected(app, event)
            app.what_pakets_value = 1;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_time_value = 0;
            app.NacitajVstupnySubor_function;
            
        end

        % Menu selected function: CSV_paketyMenu
        function CSV_paketyMenuSelected(app, event)
            app.what_pakets_value = 2;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_time_value = 0;
            app.NacitajVstupnySubor_function;
        end

        % Menu selected function: MAT_paketyMenu
        function MAT_paketyMenuSelected(app, event)
            app.what_pakets_value = 3;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_time_value = 0;
            app.NacitajVstupnySubor_function;
        end

        % Button pushed function: Button3DFunkcie_Ukoncit
        function Button3DFunkcie_UkoncitPushed(app, event)
            app.Panel_3DFunkcie.Visible = "off";
            app.UIAxes3DFunkcia.Title.String = '';
            app.UIAxes3DViewFunkcia.Title.String = '';
            app.Resetovat3DOkno_function;
        end

        % Button pushed function: ButtonNacitatVstup3D
        function ButtonNacitatVstup3DPushed(app, event)
            app.typeOfMethod = 2;
            app.NacitajVstupneData_function;
        end

        % Button pushed function: ButtonVypocitat3D
        function ButtonVypocitat3DPushed(app, event)
            
%             try
                Nt_3D = app.inputDataForParameter;
                if all(isempty(Nt_3D))||all(Nt_3D == 0)
                    message = {'Neboli načítané žiadne dáta...'};
                    uialert(app.UIFigure,message,'Warning',...
                    'Icon','warning');
                else
                    va1 = app.EditField_Hodnota1.Value;
                    va2 = app.EditField_Hodnota2.Value;
                    va3 = app.EditField_Hodnota3.Value;
                    va4 = app.EditField_Hodnota4.Value;
                    va5 = app.EditField_Hodnota5.Value;
              
                    datalineComputing3D = uiprogressdlg(app.UIFigure,'Title','Prebieha výpočet',...
                    'Indeterminate','on','Icon','membrane.png','Interpreter','html');
        
                    % v T_values su vypocitane hodnoty pre vybranu metodu
                    % pocita sa to vdaka metode v m subore = je to rychlejsie
                    app.param3D_v = createTable(Nt_3D,va1,va2,va3,va4,va5,app.func_param,app.method1_2);
                    
                    close(datalineComputing3D);
                    N_3D = length(Nt_3D);
        
                    ax = app.UIAxes3DFunkcia;
                    ax.Title.FontWeight = 'normal';    
                    ax.View = [1 3];
                    t_3D  = linspace(1,N_3D,N_3D); 
                    for t_val=2:width(app.param3D_v)
                        TV = table2array(app.param3D_v(:,t_val));
                        TV = [zeros(1,N_3D-length(TV)),TV];
                        tw = t_val*ones(1,N_3D);       
                        
                        % pre priemer a odchylku vykreslime naraz aj data
        %                 if app.func_param == 1 || app.func_param == 2 || app.func_param == 4 || app.func_param == 8
        %                     plot3(ax,t_3D,tw,Nt_3D,'-'); 
        %                     hold(ax,"on")
        %                     plot3(ax,t_3D,tw,TV,'-',Color='r', LineWidth=2);
        %                     hold(ax,"on")
        %                 else
                            plot3(ax,t_3D,tw,TV,'-', LineWidth=2);
                            hold(ax,"on")
        %                 end
                        grid(ax,"on")
                    end
        
                    axView = app.UIAxes3DViewFunkcia;
                    tcw1 = linspace(512,N_3D,N_3D-512);
                    tcw2 = linspace(512+1,N_3D,N_3D-(512+1));
                    data = table2array(app.param3D_v(:,1));
                    if app.method1_2 == 1
                        plot(axView,t_3D,Nt_3D,tcw1,data,'r');
                    elseif app.method1_2 == 2
                        plot(axView,t_3D,Nt_3D,tcw2,data,'r');
                    end
                end

%             catch
%                  uialert(app.UIFigure,app.error_message,'Error',...
%                 'Icon','error');
%             end


        end

        % Menu selected function: Menu3DPriemer
        function Menu3DPriemerSelected(app, event)
            app.NastavMenu3D(1,1,'Priemer','Náhľad dát a priemeru s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DOdchylka
        function Menu3DOdchylkaSelected(app, event)
            app.NastavMenu3D(2,1,'Smerodajná odchýlka','Náhľad dát a odchýlky s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DVariabilnost
        function Menu3DVariabilnostSelected(app, event)
            app.NastavMenu3D(3,1,'Koeficient variabilnosti','Náhľad dát a variabilnosti s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DSikmost
        function Menu3DSikmostSelected(app, event)
            app.NastavMenu3D(4,1,'Šikmosť','Náhľad dát a šikmosti s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DSpicatost
        function Menu3DSpicatostSelected(app, event)
            app.NastavMenu3D(5,1,'Špičatosť','Náhľad dát a špičatosti s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DEntropia
        function Menu3DEntropiaSelected(app, event)
            app.NastavMenu3D(7,1,'Entropia','Náhľad dát a entropie s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DEuklidovskaVelkost
        function Menu3DEuklidovskaVelkostSelected(app, event)
            app.NastavMenu3D(8,1,'Euklidovská veľkosť','Náhľad dát a euklidovskej veľkosti s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DAutoregresia
        function Menu3DAutoregresiaSelected(app, event)
            app.NastavMenu3D(9,2,'Autoregresný koeficient','Náhľad dát a autoregresie s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DKorelacia
        function Menu3DKorelaciaSelected(app, event)
            app.NastavMenu3D(10,2,'Korelácia','Náhľad dát a korelácie s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DDivergencia
        function Menu3DDivergenciaSelected(app, event)
            app.NastavMenu3D(11,2,'Divergencia','Náhľad dát a divergencie s výpočtovým oknom 512');
        end

        % Menu selected function: Menu2DPriemer
        function Menu2DPriemerSelected(app, event)
            app.NastavMenu2D(1,1,'Priemer','Vstupné dáta s vypočítaným priemerom');
        end

        % Button pushed function: ButtonVypocitat2D
        function ButtonVypocitat2DPushed(app, event)
        
                Nt_2D = app.inputDataForParameter;
                if all(isempty(Nt_2D))||all(Nt_2D == 0)
                    message = {'Neboli načítané žiadne dáta pre výpočet...'};
                    uialert(app.UIFigure,message,'Warning',...
                    'Icon','warning');
                else
                    N_2D = length(Nt_2D);
                    t_2D = linspace(1,N_2D,N_2D);
                    okno_2D = app.EditField2D_Hodnota.Value;
                    app.d = okno_2D;
                    
                    datalineComputing2D = uiprogressdlg(app.UIFigure,'Title','Prebieha výpočet',...
                    'Indeterminate','on','Icon','membrane.png','Interpreter','html', 'Cancelable','on');
        
                    m=0;
                    if app.func_param == 12
                        m = app.EditField2D_Riadky.Value;
                        step = app.EditField2D_Posun.Value;
                        app.param_v = PCA_2D(Nt_2D,okno_2D,m,step,datalineComputing2D);
                    else
                        app.param_v = calculate2D(Nt_2D,okno_2D,app.func_param,app.method1_2);
                    end
                    
                    close(datalineComputing2D);
        
                    axView = app.UIAxes2DFunkciaView;
        
                    if app.func_param == 12
                        tcw1 = linspace(okno_2D,N_2D,length(app.param_v));
                        plot(app.UIAxes2DFunkcia,tcw1,app.param_v,Color='r');
                        plot(axView,t_2D,Nt_2D,tcw1,app.param_v,'r');
                    else                
                        tcw1 = linspace(okno_2D,N_2D,N_2D-okno_2D);  
                        tcw2 = linspace(okno_2D+1,N_2D,N_2D-(okno_2D+1));
            
                        if app.method1_2 == 1
                            plot(app.UIAxes2DFunkcia,tcw1,app.param_v,Color='r');
                            plot(axView,t_2D,Nt_2D,tcw1,app.param_v,'r');
                        else
                            plot(app.UIAxes2DFunkcia,tcw2,app.param_v,Color='r');
                            plot(axView,t_2D,Nt_2D,tcw2,app.param_v,'r');
                        end
                    end
                end


        end

        % Button pushed function: ButtonNacitatVstup2D
        function ButtonNacitatVstup2DPushed(app, event)
            app.typeOfMethod = 1;
            app.NacitajVstupneData_function;
        end

        % Button pushed function: Button2DFunkcie_Ukoncit
        function Button2DFunkcie_UkoncitPushed(app, event)
            app.Panel_2DFunkcie.Visible = 'off';

            app.EditField2D_Posun.Visible = "off";
            app.EditField2D_Posun.Value = 1;
            app.PosunLabel2D_Posun.Visible = "off";
            app.LabelHodnota2D_Posun.Visible = "off";
            app.LabelHodnotaMatice.Visible = "off";
            app.EditField2D_Riadky.Visible = "off";
            app.EditField2D_Riadky.Value = 8;
            app.Label_PocetRiadkov.Visible = "off";

            app.Resetovat2DOkno_function;
        end

        % Menu selected function: Menu2DOdchylka
        function Menu2DOdchylkaSelected(app, event)
            app.NastavMenu2D(2,1,'Odchýlka','Vstupné dáta s vypočítanou odchýlkou');
        end

        % Menu selected function: Menu2DVariabilnost
        function Menu2DVariabilnostSelected(app, event)
            app.NastavMenu2D(3,1,'Variabilnosť','Vstupné dáta s vypočítanou variabilnosťou');
        end

        % Menu selected function: Menu2DSikmost
        function Menu2DSikmostSelected(app, event)
            app.NastavMenu2D(4,1,'Šikmosť','Vstupné dáta s vypočítanou šikmosťou');
        end

        % Menu selected function: Menu2DSpicatost
        function Menu2DSpicatostSelected(app, event)
            app.NastavMenu2D(5,1,'Špičatosť','Vstupné dáta s vypočítanou špičatosťou');
        end

        % Menu selected function: Menu2DEntropia
        function Menu2DEntropiaSelected(app, event)
            app.NastavMenu2D(7,1,'Entropia','Vstupné dáta s vypočítanou entropiou');
        end

        % Menu selected function: Menu2DEuklidovskaVelkost
        function Menu2DEuklidovskaVelkostSelected(app, event)
            app.NastavMenu2D(8,1,'Euklidovská veľkosť','Vstupné dáta s vypočítanou euklidovskou veľkosťou');
        end

        % Menu selected function: Menu2DAutoregresia
        function Menu2DAutoregresiaSelected(app, event)
            app.NastavMenu2D(9,2,'Autoregresia','Vstupné dáta s vypočítanou autoregresiou');
        end

        % Menu selected function: Menu2DKorelacia
        function Menu2DKorelaciaSelected(app, event)
            app.NastavMenu2D(10,2,'Korelácia','Vstupné dáta s vypočítanou koreláciou');
        end

        % Menu selected function: Menu2DDivergencia
        function Menu2DDivergenciaSelected(app, event)
            app.NastavMenu2D(11,2,'Divergencia','Vstupné dáta s vypočítanou divergenciou');
        end

        % Button pushed function: ButtonUlozFigure_b
        function ButtonUlozFigure_bPushed(app, event)
            app.StiahniFigure(app.b, app.UIAxes_KB, 2);
        end

        % Button pushed function: ButtonUlozFigure_a
        function ButtonUlozFigure_aPushed(app, event)
            app.StiahniFigure(app.a, app.UIAxes_Prirastky, 2);
        end

        % Button pushed function: ButtonVynasobParameter2D
        function ButtonVynasobParameter2DPushed(app, event)
            
            try
                hodnota_nasobenie = app.EditFieldVynasob2D.Value;
                Nt_2D = app.inputDataForParameter;
                if all(isempty(Nt_2D))||all(Nt_2D == 0)
                    message = {'Nie sú dostupné žiadne dáta pre násobenie...'};
                    uialert(app.UIFigure,message,'Warning',...
                    'Icon','warning');
                else
                    N_2D = length(Nt_2D);
                    t_2D = linspace(1,N_2D,N_2D);
                    okno_2D = app.EditField2D_Hodnota.Value;
                    m = app.EditField2D_Riadky.Value;
                    
                    axView = app.UIAxes2DFunkciaView;
                    tcw1 = linspace(okno_2D,N_2D,N_2D-okno_2D);  
                    tcw12 = linspace(okno_2D,N_2D,N_2D-(okno_2D+m));
                    tcw2 = linspace(okno_2D+1,N_2D,N_2D-(okno_2D+1));
        
                    if all(app.method1_2 == 1)&&all(app.func_param ~= 12)
                        plot(axView,t_2D,Nt_2D,tcw1,app.param_v*hodnota_nasobenie,'r');
                    elseif all(app.method1_2 == 2)&&all(app.func_param ~= 12)
                        plot(axView,t_2D,Nt_2D,tcw2,app.param_v*hodnota_nasobenie,'r');
                    else
                        tcw12 = tcw12(1:length(app.param_v));
                        plot(axView,t_2D,Nt_2D,tcw12,app.param_v*hodnota_nasobenie,'r');
                    end
                end
            
            catch
                 uialert(app.UIFigure,app.error_message,'Error',...
                'Icon','error');
            end
            
        end

        % Button pushed function: ButtonUlozParameter2D
        function ButtonUlozParameter2DPushed(app, event)
            app.StiahniData(app.param_v,'\parameter_','Nevybrali ste priečinok pre uloženie parametra...',2);
        end

        % Button pushed function: ButtonVynasobParameter3D
        function ButtonVynasobParameter3DPushed(app, event)
            
            try
                hodnota_nasobenie = app.EditFieldVynasob3D.Value;            
                Nt_3D = app.inputDataForParameter;
                if all(isempty(Nt_3D))||all(Nt_3D == 0)
                    message = {'Nie sú dostupné žiadne dáta pre ich násobenie...'};
                    uialert(app.UIFigure,message,'Warning',...
                    'Icon','warning');
                else
                    N_3D = length(Nt_3D);
                    t_3D  = linspace(1,N_3D,N_3D); 
        
                    axView = app.UIAxes3DViewFunkcia;
                    tcw1 = linspace(512,N_3D,N_3D-512);
                    tcw2 = linspace(512+1,N_3D,N_3D-(512+1));
                    data = table2array(app.param3D_v(:,1));
                    if app.method1_2 == 1
                        plot(axView,t_3D,Nt_3D,tcw1,data*hodnota_nasobenie,'r');
                    elseif app.method1_2 == 2
                        plot(axView,t_3D,Nt_3D,tcw2,data*hodnota_nasobenie,'r');
                    end
                end
            catch
                 uialert(app.UIFigure,app.error_message,'Error',...
                'Icon','error');
            end
            
        end

        % Button pushed function: ButtonZobrazHistogram_a
        function ButtonZobrazHistogram_aPushed(app, event)
            button_text_histogram = "Zobraz histogram";
            button_text_prirastky = "Zobraz prírastky";
            if app.ButtonZobrazHistogram_a.Text == button_text_histogram
                cla(app.UIAxes_Prirastky);
                pocet_tried = app.EditField_Histogram_a.Value;
                histogram(app.UIAxes_Prirastky,app.a,pocet_tried);
                app.ButtonZobrazHistogram_a.Text = button_text_prirastky;
            elseif app.ButtonZobrazHistogram_a.Text == button_text_prirastky
                cla(app.UIAxes_Prirastky);
                plot(app.UIAxes_Prirastky,app.a);
                app.ButtonZobrazHistogram_a.Text = button_text_histogram;
            end

        end

        % Button pushed function: ButtonZobrazHistogram_b
        function ButtonZobrazHistogram_bPushed(app, event)
            button_text_histogram = "Zobraz histogram";
            button_text_prirastky = "Zobraz prírastky";
            if app.ButtonZobrazHistogram_b.Text == button_text_histogram
                cla(app.UIAxes_KB);
                pocet_tried = app.EditField_Histogram_b.Value;
                histogram(app.UIAxes_KB,app.b,pocet_tried);
                app.ButtonZobrazHistogram_b.Text = button_text_prirastky;
            elseif app.ButtonZobrazHistogram_b.Text == button_text_prirastky
                cla(app.UIAxes_KB);
                plot(app.UIAxes_KB,app.b);
                app.ButtonZobrazHistogram_b.Text = button_text_histogram;
            end
        end

        % Menu selected function: KombinovanieMetodMenu
        function KombinovanieMetodMenuSelected(app, event)
            app.Panel_KombinovanieMetod.Visible = "on";
            app.ButtonKombinovanie_ResetovatPushed;
        end

        % Button pushed function: ButtonKombinovanie_Ukoncit
        function ButtonKombinovanie_UkoncitPushed(app, event)
            app.ButtonKombinovanie_ResetovatPushed;
            app.Panel_KombinovanieMetod.Visible = "off";
        end

        % Button pushed function: ButtonKombinovanie_NacitatVstup
        function ButtonKombinovanie_NacitatVstupPushed(app, event)
            app.typeOfMethod = 3;
            app.NacitajVstupneData_function;
        end

        % Button pushed function: ButtonKombinovanie_Priemer
        function ButtonKombinovanie_PriemerPushed(app, event)
             app.VypocitajParameter(1,1,app.EditField_Kombinovanie_okno.Value,app.UIAxesKombinovanie_Data,app.UIAxesKombinovanie,'r');
        end

        % Button pushed function: ButtonKombinovanie_Resetovat
        function ButtonKombinovanie_ResetovatPushed(app, event)
            app.inputDataForParameter = 0;
            cla(app.UIAxesKombinovanie);
            cla(app.UIAxesKombinovanie_Data);
            app.EditField_Kombinovanie_okno.Value = 512;
            app.LabelKombinovanie_NacitanySubor.Text = 'žiadny';
            app.EditFieldKombinovanie_Vynasob.Value = 1;
            app.param_v = 0;

            app.param_mean = 0;
            app.param_std = 0;
            app.param_variability = 0;
            app.param_skewness = 0;
            app.param_sharpness = 0;
            app.param_hurst = 0;
            app.param_entropy = 0;
            app.param_ecs = 0;
            app.param_autoregression = 0;
            app.param_correlation = 0;
            app.param_divergence = 0;
            app.param_PCA = 0;
        end

        % Button pushed function: ButtonKombinovanie_Odchylka
        function ButtonKombinovanie_OdchylkaPushed(app, event)
            app.VypocitajParameter(2,1,app.EditField_Kombinovanie_okno.Value,app.UIAxesKombinovanie_Data,app.UIAxesKombinovanie,'y');
        end

        % Button pushed function: ButtonKombinovanie_Variabilnost
        function ButtonKombinovanie_VariabilnostPushed(app, event)
            app.VypocitajParameter(3,1,app.EditField_Kombinovanie_okno.Value,app.UIAxesKombinovanie_Data,app.UIAxesKombinovanie,'g');
        end

        % Button pushed function: ButtonKombinovanie_Sikmost
        function ButtonKombinovanie_SikmostPushed(app, event)
            app.VypocitajParameter(4,1,app.EditField_Kombinovanie_okno.Value,app.UIAxesKombinovanie_Data,app.UIAxesKombinovanie,"#7E2F8E");
        end

        % Button pushed function: ButtonKombinovanie_Spicatost
        function ButtonKombinovanie_SpicatostPushed(app, event)
            app.VypocitajParameter(5,1,app.EditField_Kombinovanie_okno.Value,app.UIAxesKombinovanie_Data,app.UIAxesKombinovanie,'c');
        end

        % Button pushed function: ButtonKombinovanie_Hurst
        function ButtonKombinovanie_HurstPushed(app, event)
            app.VypocitajParameter(6,1,app.EditField_Kombinovanie_okno.Value,app.UIAxesKombinovanie_Data,app.UIAxesKombinovanie,'b');
        end

        % Button pushed function: ButtonKombinovanie_Entropia
        function ButtonKombinovanie_EntropiaPushed(app, event)
            app.VypocitajParameter(7,1,app.EditField_Kombinovanie_okno.Value,app.UIAxesKombinovanie_Data,app.UIAxesKombinovanie,'m');
        end

        % Button pushed function: ButtonKombinovanie_EuklidovskaVelkost
        function ButtonKombinovanie_EuklidovskaVelkostPushed(app, event)
            app.VypocitajParameter(8,1,app.EditField_Kombinovanie_okno.Value,app.UIAxesKombinovanie_Data,app.UIAxesKombinovanie,'k');
        end

        % Button pushed function: ButtonKombinovanie_Autoregresia
        function ButtonKombinovanie_AutoregresiaPushed(app, event)
            app.VypocitajParameter(9,2,app.EditField_Kombinovanie_okno.Value,app.UIAxesKombinovanie_Data,app.UIAxesKombinovanie,"#4DBEEE");
        end

        % Button pushed function: ButtonKombinovanie_Korelacia
        function ButtonKombinovanie_KorelaciaPushed(app, event)
            app.VypocitajParameter(10,2,app.EditField_Kombinovanie_okno.Value,app.UIAxesKombinovanie_Data,app.UIAxesKombinovanie,"#D95319");
        end

        % Button pushed function: ButtonKombinovanie_Divergencia
        function ButtonKombinovanie_DivergenciaPushed(app, event)
            app.VypocitajParameter(11,2,app.EditField_Kombinovanie_okno.Value,app.UIAxesKombinovanie_Data,app.UIAxesKombinovanie,"#77AC30");
        end

        % Button pushed function: ButtonKombinovanie_ClearGraf
        function ButtonKombinovanie_ClearGrafPushed(app, event)
            cla(app.UIAxesKombinovanie);
            app.param_v = 0;
%             app.param_mean = 0;
%             app.param_std = 0;
%             app.param_variability = 0;
%             app.param_skewness = 0;
%             app.param_sharpness = 0;
%             app.param_hurst = 0;
%             app.param_entropy = 0;
%             app.param_ecs = 0;
%             app.param_autoregression = 0;
%             app.param_correlation = 0;
%             app.param_divergence = 0;
%             app.param_PCA = 0;
        end

        % Button pushed function: ButtonKombinovanie_Vynasob_priemer
        function ButtonKombinovanie_Vynasob_priemerPushed(app, event)
            if all(app.param_mean ~= 0)
                posunuty_parameter = app.shiftParameter(app.param_mean,app.EditField_Kombinovanie_okno.Value);               
                plot(app.UIAxesKombinovanie,posunuty_parameter*app.EditFieldKombinovanie_Vynasob.Value, Color='r');
                %plot(app.UIAxesKombinovanie,app.param_mean*app.EditFieldKombinovanie_Vynasob.Value, Color='r');
            end
        end

        % Button pushed function: ButtonKombinovanie_Vynasob_odchylka
        function ButtonKombinovanie_Vynasob_odchylkaPushed(app, event)
            if all(app.param_std ~= 0)
                posunuty_parameter = app.shiftParameter(app.param_std,app.EditField_Kombinovanie_okno.Value);
                plot(app.UIAxesKombinovanie,posunuty_parameter*app.EditFieldKombinovanie_Vynasob.Value,Color='y');
               %plot(app.UIAxesKombinovanie,app.param_std*app.EditFieldKombinovanie_Vynasob.Value,Color='y');
            end
        end

        % Button pushed function: ButtonKombinovanie_Vynasob_variabilnost
        function ButtonKombinovanie_Vynasob_variabilnostPushed(app, event)
            if all(app.param_variability ~= 0)
                 posunuty_parameter = app.shiftParameter(app.param_variability,app.EditField_Kombinovanie_okno.Value);
                 plot(app.UIAxesKombinovanie,posunuty_parameter*app.EditFieldKombinovanie_Vynasob.Value,Color='g');
                 %plot(app.UIAxesKombinovanie,app.param_variability*app.EditFieldKombinovanie_Vynasob.Value,Color='g');
            end
        end

        % Button pushed function: ButtonKombinovanie_Vynasob_sikmost
        function ButtonKombinovanie_Vynasob_sikmostPushed(app, event)
            if all(app.param_skewness ~= 0)
                 posunuty_parameter = app.shiftParameter(app.param_skewness,app.EditField_Kombinovanie_okno.Value);
                 plot(app.UIAxesKombinovanie,posunuty_parameter*app.EditFieldKombinovanie_Vynasob.Value,Color="#7E2F8E");
                 %plot(app.UIAxesKombinovanie,app.param_skewness*app.EditFieldKombinovanie_Vynasob.Value,Color="#7E2F8E");
            end
        end

        % Button pushed function: ButtonKombinovanie_Vynasob_spicatost
        function ButtonKombinovanie_Vynasob_spicatostPushed(app, event)
            if all(app.param_sharpness ~= 0)
                 posunuty_parameter = app.shiftParameter(app.param_sharpness,app.EditField_Kombinovanie_okno.Value);
                 plot(app.UIAxesKombinovanie,posunuty_parameter*app.EditFieldKombinovanie_Vynasob.Value,Color='c');
                 %plot(app.UIAxesKombinovanie,app.param_sharpness*app.EditFieldKombinovanie_Vynasob.Value,Color='c');
            end
        end

        % Button pushed function: ButtonKombinovanie_Vynasob_hurst
        function ButtonKombinovanie_Vynasob_hurstPushed(app, event)
            if all(app.param_hurst ~= 0)
                 posunuty_parameter = app.shiftParameter(app.param_hurst,app.EditField_Kombinovanie_okno.Value);
                 plot(app.UIAxesKombinovanie,posunuty_parameter*app.EditFieldKombinovanie_Vynasob.Value,Color='b');
                 %plot(app.UIAxesKombinovanie,app.param_hurst*app.EditFieldKombinovanie_Vynasob.Value,Color='b');
            end
        end

        % Button pushed function: ButtonKombinovanie_Vynasob_entropia
        function ButtonKombinovanie_Vynasob_entropiaPushed(app, event)
            if all(app.param_entropy ~= 0)
                 posunuty_parameter = app.shiftParameter(app.param_entropy,app.EditField_Kombinovanie_okno.Value);
                 plot(app.UIAxesKombinovanie,posunuty_parameter*app.EditFieldKombinovanie_Vynasob.Value,Color='m');
                 %plot(app.UIAxesKombinovanie,app.param_entropy*app.EditFieldKombinovanie_Vynasob.Value,Color='m');
            end
        end

        % Button pushed function: 
        % ButtonKombinovanie_Vynasob_EuklidovskaVelkost
        function ButtonKombinovanie_Vynasob_EuklidovskaVelkostPushed(app, event)
            if all(app.param_ecs ~= 0)
                 posunuty_parameter = app.shiftParameter(app.param_ecs,app.EditField_Kombinovanie_okno.Value);
                 plot(app.UIAxesKombinovanie,posunuty_parameter*app.EditFieldKombinovanie_Vynasob.Value,Color='k');
                 %plot(app.UIAxesKombinovanie,app.param_ecs*app.EditFieldKombinovanie_Vynasob.Value,Color='k');
            end
        end

        % Button pushed function: ButtonKombinovanie_Vynasob_autoregresia
        function ButtonKombinovanie_Vynasob_autoregresiaPushed(app, event)
            if all(app.param_autoregression ~= 0)
                 posunuty_parameter = app.shiftParameter(app.param_autoregression,app.EditField_Kombinovanie_okno.Value);
                 plot(app.UIAxesKombinovanie,posunuty_parameter*app.EditFieldKombinovanie_Vynasob.Value,Color="#4DBEEE");
                 %plot(app.UIAxesKombinovanie,app.param_autoregression*app.EditFieldKombinovanie_Vynasob.Value,Color="#4DBEEE");
            end
        end

        % Button pushed function: ButtonKombinovanie_Vynasob_korelacia
        function ButtonKombinovanie_Vynasob_korelaciaPushed(app, event)
            if all(app.param_correlation ~= 0)
                 posunuty_parameter = app.shiftParameter(app.param_correlation,app.EditField_Kombinovanie_okno.Value);
                 plot(app.UIAxesKombinovanie,posunuty_parameter*app.EditFieldKombinovanie_Vynasob.Value,Color="#D95319");
                 %plot(app.UIAxesKombinovanie,app.param_correlation*app.EditFieldKombinovanie_Vynasob.Value,Color="#D95319");
            end
        end

        % Button pushed function: ButtonKombinovanie_Vynasob_divergencia
        function ButtonKombinovanie_Vynasob_divergenciaPushed(app, event)
            if all(app.param_divergence ~= 0)
                 posunuty_parameter = app.shiftParameter(app.param_divergence,app.EditField_Kombinovanie_okno.Value);
                 plot(app.UIAxesKombinovanie,posunuty_parameter*app.EditFieldKombinovanie_Vynasob.Value,Color="#77AC30");
                 %plot(app.UIAxesKombinovanie,app.param_divergence*app.EditFieldKombinovanie_Vynasob.Value,Color="#77AC30");
            end
        end

        % Menu selected function: Menu2D_PCA
        function Menu2DPCA_ordinarySelected(app, event)
            app.NastavMenu2D(12,1,'PCA','Vstupné dáta s vypočítanou PCA metódou');
            app.EditField2D_Posun.Visible = "on";
            app.PosunLabel2D_Posun.Visible = "on";
            app.LabelHodnota2D_Posun.Visible = "on";
            app.LabelHodnotaMatice.Visible = "on";
            app.EditField2D_Riadky.Visible = "on";
            app.Label_PocetRiadkov.Visible = "on";
        end

        % Button pushed function: ButtonResetovat2D
        function ButtonResetovat2DPushed(app, event)
            app.Resetovat2DOkno_function;
        end

        % Button pushed function: ButtonKombinovanie_UlozFigure
        function ButtonKombinovanie_UlozFigurePushed(app, event)
            app.StiahniFigure(app.param_v,app.UIAxesKombinovanie,2);
        end

        % Button pushed function: Button2DUloz_FigureData
        function Button2DUloz_FigureDataPushed(app, event)
            app.StiahniFigure(app.param_v,app.UIAxes2DFunkciaView,2);
        end

        % Button pushed function: Button2DUloz_FigureParameter
        function Button2DUloz_FigureParameterPushed(app, event)
            app.StiahniFigure(app.param_v,app.UIAxes2DFunkcia,2);
        end

        % Button pushed function: Button3DUloz_FigureParameter
        function Button3DUloz_FigureParameterPushed(app, event)
            app.StiahniFigure(app.param3D_v,app.UIAxes3DFunkcia,3);
        end

        % Button pushed function: Button3DUloz_FigureData
        function Button3DUloz_FigureDataPushed(app, event)
            app.StiahniFigure(app.param3D_v,app.UIAxes3DViewFunkcia,2);
        end

        % Button pushed function: Button3DFunkcie_Resetovat
        function Button3DFunkcie_ResetovatPushed(app, event)
            app.Resetovat3DOkno_function;
        end

        % Value changed function: EditFieldVynasob3D
        function EditFieldVynasob3DValueChanged(app, event)
            value = app.EditFieldVynasob3D.Value;
            
        end

        % Menu selected function: MenuPredikcie
        function MenuPredikcieSelected(app, event)
            app.Panel_Predikcia.Visible = "on";
            app.UIAxes_Predikcia_tunel.Title.String = "sigma tunel";
        end

        % Button pushed function: ButtonPredikcia_NacitatVstup
        function ButtonPredikcia_NacitatVstupPushed(app, event)
            app.PanelPredikcia_NacitajVstupneData.Visible ="on";
        end

        % Button pushed function: ButtonPredikcia_Vypocitat
        function ButtonPredikcia_VypocitatPushed(app, event)
            try
                % tu sa nastavia zakladne premenne ako je predikcne okno a
                % cas utoku, ktory uzivatel musi urcit
                app.dlzkaPredikcnehoOkna = app.EditFieldPredikcia_NastavOkno.Value;
                app.casUtoku = app.EditFieldPanelPredikcia_CasUtoku.Value;
                app.casUtoku = app.casUtoku - app.dlzkaPredikcnehoOkna;
    
                % pre istotu keby sa pouzivatel pomylil a zaskrtol by chceckbox
                % pre vykreslenie okolo tunela a zaroven
                % by nastavil rozsah vykreslenia...
                value = app.CheckBoxPredikcia_Rozsah.Value;
                if value == 1
                    app.od = app.casUtoku - app.EditFieldPredikcia_Rozsah.Value;
                    app.do = app.casUtoku + app.EditFieldPredikcia_Rozsah.Value;
                else
                    app.do = app.EditFieldPredikcia_Do.Value;
                    app.od = app.EditFieldPredikcia_Od.Value;
                    if app.od > app.do - 100
                        app.od = app.do - 100;
                    end
                end
    
                %//////////////////////////////////////////////////////
                % kontrola vstupu do predikcnych metod   
    
                app.vystup = length(app.data_predikcia) - 1;
    
                if app.do > length(app.data_predikcia)
                    app.do = length(app.data_predikcia) - 1;
                end
    
                if app.od > (length(app.data_predikcia))
                    app.od = length(app.data_predikcia) - 1;
                end            
                app.do = app.do - 1;  
    
                %///////////////////////////////////////////////////////
    
                app.alfa = app.EditField_Alfa.Value;
                app.beta = app.EditField_Beta.Value;
                app.bb = app.EditField_B.Value;
                app.sigma = app.EditFieldPredikcia_sigmy.Value;
                
                % vykonanie vyhladenia
                switch app.vyhladenie
                    case 1
                        app.vyhladene_data = exponencialne_vyhladenie(app.data_predikcia, app.alfa);
                    case 2
                        app.vyhladene_data = modif_holtovo_vyhladenie(app.data_predikcia, app.alfa, app.beta, app.bb);
                    otherwise
                        app.vyhladene_data = app.data_predikcia;
                end

                % //////////////////////////// PREBIEHA VYPOCET PREDIKCIE//////////////////////////
    
                dataline_tunel = uiprogressdlg(app.UIFigure,'Title','Čakajte prosím','Icon','membrane.png',...
                'Interpreter','html','Message','Opening the application');
                pause(.5)

                dataline_tunel.Value = .33; 
                dataline_tunel.Message = 'Prebieha výpočet predikčnej metódy...';
                pause(1)

                % vytvorenie tunela
                switch app.predikcna_netoda 
                    case 1
                        app.tunel = dve_sigmy(app.vyhladene_data,app.dlzkaPredikcnehoOkna,app.zaciatok,app.vystup,app.sigma);
                    case 2
                        app.tunel = regresny_polynom(app.vyhladene_data,app.dlzkaPredikcnehoOkna,app.pocetPredikovanych,app.zaciatok,app.vystup,app.polynom,app.sigma);
                    case 3
                        app.tunel = fourierova_transformacia(app.vyhladene_data,app.dlzkaPredikcnehoOkna,app.pocetPredikovanych,app.zaciatok,app.vystup,0,app.sigma);
                    case 4
                        app.tunel = autoregresia(app.vyhladene_data,app.dlzkaPredikcnehoOkna,app.pocetPredikovanych,app.zaciatok,app.vystup,app.sigma);
                    otherwise
                        app.tunel = modifikovana_autoregresia(app.vyhladene_data,app.dlzkaPredikcnehoOkna,app.pocetPredikovanych,app.zaciatok,app.vystup,app.kalibracia,app.sigma);
                end

               
                dataline_tunel.Value = 1;
                dataline_tunel.Message = 'Koniec';
                pause(1)
                close(dataline_tunel)

                % //////////////////////////// KONIEC VYPOCETU PREDIKCIE//////////////////////////

    
                % Vypocet dolnej a hornej hranice
                % hh                
                if app.predikcna_netoda == 5 
                    app.centrHore = app.data_predikcia(app.kalibracia + app.dlzkaPredikcnehoOkna + 1: app.kalibracia+ app.vystup - app.kalibracia) - app.tunel(1,:);
                else
                    app.centrHore = app.data_predikcia(app.dlzkaPredikcnehoOkna + 1 : app.dlzkaPredikcnehoOkna + app.vystup - app.dlzkaPredikcnehoOkna) - app.tunel(1,:);
                end

                % dh
                if app.predikcna_netoda == 5 
                    app.centrDole = app.data_predikcia(app.kalibracia + app.dlzkaPredikcnehoOkna+ 1 : app.kalibracia + app.vystup - app.kalibracia) - app.tunel(2,:);
                else
                    app.centrDole = app.data_predikcia(app.dlzkaPredikcnehoOkna + 1 : app.dlzkaPredikcnehoOkna + app.vystup - app.dlzkaPredikcnehoOkna) - app.tunel(2,:);                
                end
                
    
                %/////////////////////////////////////////////////////////////
                % VYPOCET EFEKTIVITY
    
                % pocet falosnych utokov
                pocetFalosnych = 0;
                if app.predikcna_netoda == 5
                    for t = 2:app.casUtoku-(app.zaciatok+app.kalibracia+app.dlzkaPredikcnehoOkna)
                        if all(app.centrHore(t) > 0)&&all(app.centrHore(t-1) <= 0)
                            pocetFalosnych = pocetFalosnych + 1;
                        end
                        if all(app.centrDole(t) < 0)&&all(app.centrDole(t-1) >= 0)
                            pocetFalosnych = pocetFalosnych + 1;
                        end
                    end
                else
                    for t = 2:app.casUtoku-(app.zaciatok+app.dlzkaPredikcnehoOkna)
                        if all(app.centrHore(t) > 0)&&all(app.centrHore(t-1) <= 0)
                            pocetFalosnych = pocetFalosnych + 1;
                        end
                        if all(app.centrDole(t) < 0)&&all(app.centrDole(t-1) >= 0)
                            pocetFalosnych = pocetFalosnych + 1;
                        end
                    end
                end
                app.pocesFalosnychHlaseni = pocetFalosnych;


                % dlzka falosnych hlaseni
                pocetFalosnychSlotov = 0;
                if app.predikcna_netoda == 5
                    for t = 2:app.casUtoku-(app.zaciatok+app.kalibracia+app.dlzkaPredikcnehoOkna)
                        if app.centrHore(t) > 0
                            pocetFalosnychSlotov = pocetFalosnychSlotov + 1;            
                        end
                        if app.centrDole(t) < 0
                            pocetFalosnychSlotov = pocetFalosnychSlotov + 1;
                        end
                    end
                else
                    for t = 2:app.casUtoku-(app.zaciatok+app.dlzkaPredikcnehoOkna)
                        if app.centrHore(t) > 0
                            pocetFalosnychSlotov = pocetFalosnychSlotov + 1;
                        end
                        if app.centrDole(t) < 0
                            pocetFalosnychSlotov = pocetFalosnychSlotov + 1;
                        end
                    end
                end
                app.dlzkaFalosnychHlaseni = pocetFalosnychSlotov;

    
                % cas rozpoznania od zaciatku utoku
                casHore = 1;
                casDole = 1;
                if app.predikcna_netoda == 5
                    t = app.casUtoku-(app.zaciatok+app.kalibracia+app.dlzkaPredikcnehoOkna);
                else
                    t = app.casUtoku-(app.zaciatok+app.dlzkaPredikcnehoOkna); 
                end  
                
                while all(app.centrHore(t) > 0)&&all(app.centrHore(t-1) > 0)
                    casHore = casHore + 1;
                    t = t+1;
                end
    
                while app.centrHore(t) < 0
                    casHore = casHore + 1;
                    if t < length(app.centrHore)
                        t = t + 1;
                    else
                        break;
                    end
                end


                if app.predikcna_netoda == 5
                    t = app.casUtoku-(app.zaciatok+app.kalibracia+app.dlzkaPredikcnehoOkna)+1;
                else
                    t = app.casUtoku-(app.zaciatok+app.dlzkaPredikcnehoOkna)+1;
                end 
                    
                
                while all(app.centrDole(t) < 0)&&all(app.centrDole(t-1) < 0)
                    casDole = casDole + 1;
                    t = t + 1;
                end
                
                while app.centrDole(t) > 0
                    casDole = casDole + 1;
                    if t < length(app.centrDole)
                        t = t + 1;
                    else
                        break;
                    end
                end
                app.casRozpoznania = min(casDole,casHore);
    
                app.LabelPredikcia_RozpoznanieVysledok.Text = num2str(app.casRozpoznania);
                app.LabelPredikcia_DlzkaVysledok.Text = num2str(app.dlzkaFalosnychHlaseni);
                app.LabelPredikcia_PocetVysledok.Text = num2str(app.pocesFalosnychHlaseni);
    
                % PLOT
                timeVystup = app.od:app.do;
                povodnyVykreslenie = app.data_predikcia(app.od:app.do);


                if app.predikcna_netoda == 5
                    timeTunel = app.od+app.dlzkaPredikcnehoOkna+app.kalibracia:app.do;
                    tunelVykreslenie = app.tunel(:,app.od:app.do-app.dlzkaPredikcnehoOkna-app.kalibracia);
                else
                    timeTunel = app.od+app.dlzkaPredikcnehoOkna:app.do;
                    tunelVykreslenie = app.tunel(:,app.od:app.do-app.dlzkaPredikcnehoOkna);
                end
    
                 % if - ak sa vykresli aj ciara utoku
                 % else - ak je rozsah vykreslenia mimo utoku a teda ciara sa
                 % nevykresli
                if all(app.casUtoku > app.od)&&all(app.casUtoku < app.do) 
                    % plot tunel
                    plot(app.UIAxes_Predikcia_tunel,timeVystup,povodnyVykreslenie,'b',timeTunel,tunelVykreslenie(1,:),'k',timeTunel,tunelVykreslenie(2,:),'k', [app.od+app.dlzkaPredikcnehoOkna app.od+app.dlzkaPredikcnehoOkna],[min(tunelVykreslenie(2,:)) max(tunelVykreslenie(1,:))], 'black--', [app.casUtoku app.casUtoku],[min(tunelVykreslenie(2,:)) max(tunelVykreslenie(1,:))], 'black--')
                    xlim(app.UIAxes_Predikcia_tunel, [min(timeVystup) max(timeVystup)]); 
                   
                    % plot predikcia
                    if app.predikcna_netoda ~= 1
                        plot(app.UIAxes_Predikcia_predikcia,timeVystup,povodnyVykreslenie,'b',timeTunel,tunelVykreslenie(3,:),'r--',[app.casUtoku app.casUtoku],[min(povodnyVykreslenie) max(povodnyVykreslenie)], 'black--')
                    else 
                        plot(app.UIAxes_Predikcia_predikcia,0,0) % pretoze sigma tunel nema aproximaxciu nakolko to nie je predikcia...
                    end
                    xlim(app.UIAxes_Predikcia_predikcia, [min(timeVystup) max(timeVystup)]);
    
                    switch app.predikcna_netoda 
                        case 1
                            app.UIAxes_Predikcia_tunel.Title.String = 'sigma tunel';     
                        case 2
                            app.UIAxes_Predikcia_tunel.Title.String = 'Regresný tunel';
                    end

                    switch app.predikcna_netoda 
                        case 1
                            app.UIAxes_Predikcia_tunel.Title.String = 'sigma tunel';      
                        case 2
                            app.UIAxes_Predikcia_tunel.Title.String = 'Regresný tunel';
                        case 3
                            app.UIAxes_Predikcia_tunel.Title.String = 'DFT tunel';
                        case 4
                            app.UIAxes_Predikcia_tunel.Title.String = 'AR(1) tunel';
                        otherwise
                            app.UIAxes_Predikcia_tunel.Title.String = 'AR(m,n) tunel';
                    end
        
                    % plot horna hranica
                    if app.predikcna_netoda == 5
                        plot(app.UIAxes_Predikcia_HH,timeTunel,app.centrHore(app.od:app.do-app.dlzkaPredikcnehoOkna-app.kalibracia),timeVystup,0 * (app.od:app.do), 'black',[app.casUtoku app.casUtoku], [min(app.centrHore) max(app.centrHore)], 'black--')
                    else
                        plot(app.UIAxes_Predikcia_HH,timeTunel,app.centrHore(app.od:app.do-app.dlzkaPredikcnehoOkna),timeVystup,0 * (app.od:app.do), 'black',[app.casUtoku app.casUtoku],[min(app.centrHore) max(app.centrHore)], 'black--')
                    end
                    xlim(app.UIAxes_Predikcia_HH, [min(timeVystup) max(timeVystup)]);


                    % plot dolna hranica
                    if app.predikcna_netoda == 5
                        plot(app.UIAxes_Predikcia_DH,timeTunel,app.centrDole(app.od:app.do-app.dlzkaPredikcnehoOkna-app.kalibracia),timeVystup, 0 * (app.od:app.do), 'black',[app.casUtoku app.casUtoku],[min(app.centrDole) max(app.centrDole)], 'black--')
                    else
                        plot(app.UIAxes_Predikcia_DH,timeTunel,app.centrDole(app.od:app.do-app.dlzkaPredikcnehoOkna),timeVystup, 0 * (app.od:app.do), 'black',[app.casUtoku app.casUtoku],[min(app.centrDole) max(app.centrDole)], 'black--')
                    end
                    xlim(app.UIAxes_Predikcia_DH, [min(timeVystup) max(timeVystup)]);

                else
                    % plot tunel
                    plot(app.UIAxes_Predikcia_tunel,timeVystup,povodnyVykreslenie,'b',timeTunel,tunelVykreslenie(1,:),'k',timeTunel,tunelVykreslenie(2,:),'k',[app.od+app.dlzkaPredikcnehoOkna app.od+app.dlzkaPredikcnehoOkna],[min(tunelVykreslenie(2,:)) max(tunelVykreslenie(1,:))], 'black--')
                    xlim(app.UIAxes_Predikcia_tunel, [min(timeVystup) max(timeVystup)]);       
        
                    % plot predikcia
                    if app.predikcna_netoda ~= 1
                        plot(app.UIAxes_Predikcia_predikcia,timeVystup,povodnyVykreslenie,'b',timeTunel,tunelVykreslenie(3,:),'r--')
                    else 
                        plot(app.UIAxes_Predikcia_predikcia,0,0) % pretoze sigma tunel nema aproximaxciu nakolko to nie je predikcia...
                    end
                    xlim(app.UIAxes_Predikcia_predikcia, [min(timeVystup) max(timeVystup)]);
    
                    switch app.predikcna_netoda 
                        case 1
                            app.TunelUIAxes.Title.String = 'sigma tunel';     
                        case 2
                            app.TunelUIAxes.Title.String = 'Regresný tunel';
                        case 3
                            app.TunelUIAxes.Title.String = 'DFT tunel';
                        case 4
                            app.TunelUIAxes.Title.String = 'AR(1) tunel';
                        otherwise
                            app.TunelUIAxes.Title.String = 'AR(m,n) tunel';
                    end
        
                    % plot horna hranica
                    if app.predikcna_netoda == 5
                        plot(app.UIAxes_Predikcia_HH,timeTunel,app.centrHore(app.od:app.do-app.dlzkaPredikcnehoOkna-app.kalibracia),timeVystup,0 * (app.od:app.do), 'black')
                    else
                        plot(app.UIAxes_Predikcia_HH,timeTunel,app.centrHore(app.od:app.do-app.dlzkaPredikcnehoOkna),timeVystup,0 * (app.od:app.do), 'black')
                    end
                    xlim(app.UIAxes_Predikcia_HH, [min(timeVystup) max(timeVystup)]);


                    % plot dolna hranica
                    if app.predikcna_netoda == 5
                        plot(app.UIAxes_Predikcia_DH,timeTunel,app.centrDole(app.od:app.do-app.dlzkaPredikcnehoOkna-app.kalibracia),timeVystup, 0 * (app.od:app.do), 'black')
                    else
                        plot(app.UIAxes_Predikcia_DH,timeTunel,app.centrDole(app.od:app.do-app.dlzkaPredikcnehoOkna),timeVystup, 0 * (app.od:app.do), 'black')
                    end
                    xlim(app.UIAxes_Predikcia_DH, [min(timeVystup) max(timeVystup)]);                  
                    
                end

            catch
                uialert(app.UIFigure,app.error_message,'Error',...
                'Icon','error');
            end

        end

        % Clicked callback: DropDownPredikcia_Vyhladenie
        function DropDownPredikcia_VyhladenieClicked(app, event)
            switch app.DropDownPredikcia_Vyhladenie.Value
                case 'Žiadne'
                    app.vyhladenie = 0;
                    app.EditField_Alfa.Visible = "off";
                    app.EditField_Beta.Visible = "off";
                    app.EditField_B.Visible = "off";
                    app.Label_Alfa.Visible = "off";
                    app.Label_Beta.Visible = "off";
                    app.Label_B.Visible = "off";
                case 'Exponenciálne'
                    app.vyhladenie = 1;
                    app.EditField_Alfa.Visible = "on";
                    app.EditField_Beta.Visible = "off";
                    app.EditField_B.Visible = "off";
                    app.Label_Alfa.Visible = "on";
                    app.Label_Beta.Visible = "off";
                    app.Label_B.Visible = "off";
                case 'Holt'
                    app.vyhladenie = 2;
                    app.EditField_Alfa.Visible = "on";
                    app.EditField_Beta.Visible = "on";
                    app.EditField_B.Visible = "on";
                    app.Label_Alfa.Visible = "on";
                    app.Label_Beta.Visible = "on";
                    app.Label_B.Visible = "on";
            end
            
        end

        % Button pushed function: ButtonPredikcia_Ukoncit
        function ButtonPredikcia_UkoncitPushed(app, event)
            % vycistime si premenne a grafy po ukonceni predikcie
            app.Panel_Predikcia.Visible ="off";

            app.data_predikcia = 0;
            app.vyhladene_data = 0;
            app.tunel = 0;
            app.predikcna_netoda = 1;
            app.od = 1;
            app.do = 100000;
    
            app.casUtoku = 0;
            app.vyhladenie = 0;
    
            app.alfa = 0.05;
            app.beta = 0.8;
            app.bb = 0.5;
    
            app.zaciatok = 1;   
            app.vystup = 100000;
    
            app.dlzkaPredikcnehoOkna = 100;  
            app.pocetPredikovanych = 10;   
    
            app.sigma = 2; 
            app.polynom = 2; 

            app.LabelPanelPredikcia_NacitanySubor.Text = 'žiadny';

            app.LabelPredikcia_RozpoznanieVysledok.Text = '0';
            app.LabelPredikcia_PocetVysledok.Text = '0';
            app.LabelPredikcia_DlzkaVysledok.Text = '0';

            app.DropDownPredikcia_Tunel.Value = 'Sigma tunel';
            app.DropDownPredikcia_Vyhladenie.Value = 'Žiadne';
            app.CheckBoxPredikcia_Rozsah.Value = 0;

            app.EditFieldPanelPredikcia_CasUtoku.Value = 0;
            app.LabelPanelPredikcia_NacitanySubor.Text = 'žiadny';
            cla(app.UIAxesPanelPredikcia_Parameter);

            cla(app.UIAxesPanelPredikcia_Parameter);
            cla(app.UIAxes_Predikcia_DH);
            cla(app.UIAxes_Predikcia_HH);
            cla(app.UIAxes_Predikcia_predikcia);
            cla(app.UIAxes_Predikcia_tunel);
        end

        % Clicked callback: DropDownPredikcia_Tunel
        function DropDownPredikcia_TunelClicked(app, event)
            switch app.DropDownPredikcia_Tunel.Value
                case 'Sigma tunel'
                    app.predikcna_netoda = 1;
                    app.EditFieldPredikcia_StupenPolynomu.Visible = "off";
                    app.StupepolynmuEditFieldLabel.Visible ="off";
                case 'Regresný polynóm'   
                    app.predikcna_netoda = 2;
                    app.EditFieldPredikcia_StupenPolynomu.Visible = "on";
                    app.StupepolynmuEditFieldLabel.Visible ="on";
                    app.StupepolynmuEditFieldLabel.Text = 'Stupeň polynómu:';
                    app.EditFieldPredikcia_StupenPolynomu.Value = 2;
                case 'DFT'
                    app.predikcna_netoda = 3;
                    app.EditFieldPredikcia_StupenPolynomu.Visible = "off";
                    app.StupepolynmuEditFieldLabel.Visible ="off";
                case 'AR(n)'
                    app.predikcna_netoda = 4;
                    app.EditFieldPredikcia_StupenPolynomu.Visible = "off";
                    app.StupepolynmuEditFieldLabel.Visible ="off";
                otherwise
                    app.predikcna_netoda = 5;
                    app.EditFieldPredikcia_StupenPolynomu.Visible = "on";
                    app.StupepolynmuEditFieldLabel.Visible ="on";
                    app.StupepolynmuEditFieldLabel.Text = 'Kalibrácia:';
                    app.EditFieldPredikcia_StupenPolynomu.Value = 400;
            end
            
        end

        % Button pushed function: ButtonPanelPredikcia_Vstup
        function ButtonPanelPredikcia_VstupPushed(app, event)
            app.typeOfMethod = 4;
            app.NacitajVstupneData_function;
            app.EditFieldPredikcia_Do.Value = length(app.data_predikcia);
        end

        % Button pushed function: ButtonPanelPredikcia_PotvrdCasUtoku
        function ButtonPanelPredikcia_PotvrdCasUtokuPushed(app, event)
            app.PanelPredikcia_NacitajVstupneData.Visible ="off";
            if ~isempty(app.data_predikcia)
                app.ButtonPredikcia_Vypocitat.Visible ="on";
            else
                message = {'Neboli načítané žiadne dáta prosím zopakujte načítanie vstupu...'};
                uialert(app.UIFigure,message,'Warning',...
                'Icon','warning');
            end
            
        end

        % Value changed function: EditFieldPredikcia_Rozsah
        function EditFieldPredikcia_RozsahValueChanged(app, event)
            okolo = app.EditFieldPredikcia_Rozsah.Value;
            if okolo < 200
                okolo = 200;
            end

            if okolo > length(app.data_predikcia) / 2 - 1
                okolo = length(app.data_predikcia) / 2 - 1;
            end
            app.od = app.casUtoku - okolo;
            app.do = app.casUtoku + okolo;
        end

        % Value changed function: CheckBoxPredikcia_Rozsah
        function CheckBoxPredikcia_RozsahValueChanged(app, event)
            value = app.CheckBoxPredikcia_Rozsah.Value;
            if value == 1
                app.od = app.casUtoku - app.EditFieldPredikcia_Rozsah.Value;
                app.do = app.casUtoku + app.EditFieldPredikcia_Rozsah.Value;
            else
                app.do = app.EditFieldPredikcia_Do.Value;
                app.od = app.EditFieldPredikcia_Od.Value;
                if app.od > app.do - 100
                    app.od = app.do - 100;
                end
            end
        end

        % Menu selected function: Menu2DHurst_Default
        function Menu2DHurst_DefaultSelected(app, event)
            app.NastavMenu2D(6,1,'Hurst','Vstupné dáta s vypočítaným Hurstom');
        end

        % Menu selected function: Menu2DHurst_RS
        function Menu2DHurst_RSSelected(app, event)
            app.NastavMenu2D(61,1,'Hurst','Vstupné dáta s vypočítaným Hurstom');
        end

        % Menu selected function: Menu2DHurst_DFA
        function Menu2DHurst_DFASelected(app, event)
            app.NastavMenu2D(62,1,'Hurst','Vstupné dáta s vypočítaným Hurstom');
        end

        % Menu selected function: Menu2DHurst_Abs
        function Menu2DHurst_AbsSelected(app, event)
            app.NastavMenu2D(63,1,'Hurst','Vstupné dáta s vypočítaným Hurstom');
        end

        % Menu selected function: Menu3DHurst_Default
        function Menu3DHurst_DefaultSelected(app, event)
            app.NastavMenu3D(6,1,'Hurstov koeficient','Náhľad dát a hursta s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DHurst_RS
        function Menu3DHurst_RSSelected(app, event)
            app.NastavMenu3D(61,1,'Hurstov koeficient','Náhľad dát a hursta s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DHurst_DFA
        function Menu3DHurst_DFASelected(app, event)
            app.NastavMenu3D(62,1,'Hurstov koeficient','Náhľad dát a hursta s výpočtovým oknom 512');
        end

        % Menu selected function: Menu3DHurst_Abs
        function Menu3DHurst_AbsSelected(app, event)
            app.NastavMenu3D(63,1,'Hurstov koeficient','Náhľad dát a hursta s výpočtovým oknom 512');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VstupyMenu = uimenu(app.UIFigure);
            app.VstupyMenu.Text = 'Vstupy';
            app.CasyMenu = uimenu(app.VstupyMenu);
            app.CasyMenu.Text = 'Čas z';
            app.XLSX_suborMenu = uimenu(app.CasyMenu);
            app.XLSX_suborMenu.MenuSelectedFcn = createCallbackFcn(app, @XLSX_suborMenuMenuSelected, true);
            app.XLSX_suborMenu.Text = '.xlsx subor';
            app.CSV_suborMenu = uimenu(app.CasyMenu);
            app.CSV_suborMenu.MenuSelectedFcn = createCallbackFcn(app, @CSV_suborMenuSelected, true);
            app.CSV_suborMenu.Text = '.csv subor';
            app.MAT_suborMenu = uimenu(app.CasyMenu);
            app.MAT_suborMenu.MenuSelectedFcn = createCallbackFcn(app, @MAT_suborMenuSelected, true);
            app.MAT_suborMenu.Text = '.mat subor';
            app.PaketyzMenu = uimenu(app.VstupyMenu);
            app.PaketyzMenu.Text = 'Pakety z';
            app.XLSX_paketyMenu = uimenu(app.PaketyzMenu);
            app.XLSX_paketyMenu.MenuSelectedFcn = createCallbackFcn(app, @XLSX_paketyMenuSelected, true);
            app.XLSX_paketyMenu.Text = '.xlsx subor';
            app.CSV_paketyMenu = uimenu(app.PaketyzMenu);
            app.CSV_paketyMenu.MenuSelectedFcn = createCallbackFcn(app, @CSV_paketyMenuSelected, true);
            app.CSV_paketyMenu.Text = '.csv subor';
            app.MAT_paketyMenu = uimenu(app.PaketyzMenu);
            app.MAT_paketyMenu.MenuSelectedFcn = createCallbackFcn(app, @MAT_paketyMenuSelected, true);
            app.MAT_paketyMenu.Text = '.mat subor';
            app.PCAPsuborMenu = uimenu(app.VstupyMenu);
            app.PCAPsuborMenu.MenuSelectedFcn = createCallbackFcn(app, @PCAPfileMenuSelected, true);
            app.PCAPsuborMenu.Text = 'PCAP súbor';
            app.FunkcionalityMenu = uimenu(app.UIFigure);
            app.FunkcionalityMenu.Text = 'Funkcionality';
            app.UpravaVstupuMenu = uimenu(app.FunkcionalityMenu);
            app.UpravaVstupuMenu.MenuSelectedFcn = createCallbackFcn(app, @UpravaVstupuMenuMenuSelected, true);
            app.UpravaVstupuMenu.Text = 'Upravenie vstupu pre IMPORT';
            app.KombinovanieMetodMenu = uimenu(app.FunkcionalityMenu);
            app.KombinovanieMetodMenu.MenuSelectedFcn = createCallbackFcn(app, @KombinovanieMetodMenuSelected, true);
            app.KombinovanieMetodMenu.Text = 'Testovanie metód';
            app.Funkcie2DMenu = uimenu(app.UIFigure);
            app.Funkcie2DMenu.Text = 'Funkcie';
            app.Menu2DPriemer = uimenu(app.Funkcie2DMenu);
            app.Menu2DPriemer.MenuSelectedFcn = createCallbackFcn(app, @Menu2DPriemerSelected, true);
            app.Menu2DPriemer.Text = 'Priemer';
            app.Menu2DOdchylka = uimenu(app.Funkcie2DMenu);
            app.Menu2DOdchylka.MenuSelectedFcn = createCallbackFcn(app, @Menu2DOdchylkaSelected, true);
            app.Menu2DOdchylka.Text = 'Odchýlka';
            app.Menu2DVariabilnost = uimenu(app.Funkcie2DMenu);
            app.Menu2DVariabilnost.MenuSelectedFcn = createCallbackFcn(app, @Menu2DVariabilnostSelected, true);
            app.Menu2DVariabilnost.Text = 'Variabilnosť';
            app.Menu2DSikmost = uimenu(app.Funkcie2DMenu);
            app.Menu2DSikmost.MenuSelectedFcn = createCallbackFcn(app, @Menu2DSikmostSelected, true);
            app.Menu2DSikmost.Text = 'Šikmosť';
            app.Menu2DSpicatost = uimenu(app.Funkcie2DMenu);
            app.Menu2DSpicatost.MenuSelectedFcn = createCallbackFcn(app, @Menu2DSpicatostSelected, true);
            app.Menu2DSpicatost.Text = 'Špičatosť';
            app.Menu2DHurst = uimenu(app.Funkcie2DMenu);
            app.Menu2DHurst.Text = 'Hurst';
            app.Menu2DHurst_RS = uimenu(app.Menu2DHurst);
            app.Menu2DHurst_RS.MenuSelectedFcn = createCallbackFcn(app, @Menu2DHurst_RSSelected, true);
            app.Menu2DHurst_RS.Text = 'RS';
            app.Menu2DHurst_DFA = uimenu(app.Menu2DHurst);
            app.Menu2DHurst_DFA.MenuSelectedFcn = createCallbackFcn(app, @Menu2DHurst_DFASelected, true);
            app.Menu2DHurst_DFA.Text = 'DFA';
            app.Menu2DHurst_Abs = uimenu(app.Menu2DHurst);
            app.Menu2DHurst_Abs.MenuSelectedFcn = createCallbackFcn(app, @Menu2DHurst_AbsSelected, true);
            app.Menu2DHurst_Abs.Text = 'Absolut';
            app.Menu2DHurst_Default = uimenu(app.Menu2DHurst);
            app.Menu2DHurst_Default.MenuSelectedFcn = createCallbackFcn(app, @Menu2DHurst_DefaultSelected, true);
            app.Menu2DHurst_Default.Text = 'Default';
            app.Menu2DEntropia = uimenu(app.Funkcie2DMenu);
            app.Menu2DEntropia.MenuSelectedFcn = createCallbackFcn(app, @Menu2DEntropiaSelected, true);
            app.Menu2DEntropia.Text = 'Entropia';
            app.Menu2DEuklidovskaVelkost = uimenu(app.Funkcie2DMenu);
            app.Menu2DEuklidovskaVelkost.MenuSelectedFcn = createCallbackFcn(app, @Menu2DEuklidovskaVelkostSelected, true);
            app.Menu2DEuklidovskaVelkost.Text = 'Euklidovská veľkosť';
            app.Menu2DAutoregresia = uimenu(app.Funkcie2DMenu);
            app.Menu2DAutoregresia.MenuSelectedFcn = createCallbackFcn(app, @Menu2DAutoregresiaSelected, true);
            app.Menu2DAutoregresia.Text = 'Autoregresia';
            app.Menu2DKorelacia = uimenu(app.Funkcie2DMenu);
            app.Menu2DKorelacia.MenuSelectedFcn = createCallbackFcn(app, @Menu2DKorelaciaSelected, true);
            app.Menu2DKorelacia.Text = 'Korelácia';
            app.Menu2DDivergencia = uimenu(app.Funkcie2DMenu);
            app.Menu2DDivergencia.MenuSelectedFcn = createCallbackFcn(app, @Menu2DDivergenciaSelected, true);
            app.Menu2DDivergencia.Text = 'Divergencia';
            app.Menu2D_PCA = uimenu(app.Funkcie2DMenu);
            app.Menu2D_PCA.MenuSelectedFcn = createCallbackFcn(app, @Menu2DPCA_ordinarySelected, true);
            app.Menu2D_PCA.Text = 'PCA';
            app.Funkcie3DMenu = uimenu(app.UIFigure);
            app.Funkcie3DMenu.Text = 'Funkcie 3D';
            app.Menu3DPriemer = uimenu(app.Funkcie3DMenu);
            app.Menu3DPriemer.MenuSelectedFcn = createCallbackFcn(app, @Menu3DPriemerSelected, true);
            app.Menu3DPriemer.Text = 'Priemer 3D';
            app.Menu3DPriemer.Tag = 'priemer';
            app.Menu3DOdchylka = uimenu(app.Funkcie3DMenu);
            app.Menu3DOdchylka.MenuSelectedFcn = createCallbackFcn(app, @Menu3DOdchylkaSelected, true);
            app.Menu3DOdchylka.Text = 'Odchýlka 3D';
            app.Menu3DOdchylka.Tag = 'odchylka';
            app.Menu3DVariabilnost = uimenu(app.Funkcie3DMenu);
            app.Menu3DVariabilnost.MenuSelectedFcn = createCallbackFcn(app, @Menu3DVariabilnostSelected, true);
            app.Menu3DVariabilnost.Text = 'Variabilnosť 3D';
            app.Menu3DSikmost = uimenu(app.Funkcie3DMenu);
            app.Menu3DSikmost.MenuSelectedFcn = createCallbackFcn(app, @Menu3DSikmostSelected, true);
            app.Menu3DSikmost.Text = 'Šikmosť 3D';
            app.Menu3DSpicatost = uimenu(app.Funkcie3DMenu);
            app.Menu3DSpicatost.MenuSelectedFcn = createCallbackFcn(app, @Menu3DSpicatostSelected, true);
            app.Menu3DSpicatost.Text = 'Špičatosť 3D';
            app.Menu3DHurst = uimenu(app.Funkcie3DMenu);
            app.Menu3DHurst.Text = 'Hurst 3D';
            app.Menu3DHurst_RS = uimenu(app.Menu3DHurst);
            app.Menu3DHurst_RS.MenuSelectedFcn = createCallbackFcn(app, @Menu3DHurst_RSSelected, true);
            app.Menu3DHurst_RS.Text = 'RS';
            app.Menu3DHurst_DFA = uimenu(app.Menu3DHurst);
            app.Menu3DHurst_DFA.MenuSelectedFcn = createCallbackFcn(app, @Menu3DHurst_DFASelected, true);
            app.Menu3DHurst_DFA.Text = 'DFA';
            app.Menu3DHurst_Abs = uimenu(app.Menu3DHurst);
            app.Menu3DHurst_Abs.MenuSelectedFcn = createCallbackFcn(app, @Menu3DHurst_AbsSelected, true);
            app.Menu3DHurst_Abs.Text = 'Absolut';
            app.Menu3DHurst_Default = uimenu(app.Menu3DHurst);
            app.Menu3DHurst_Default.MenuSelectedFcn = createCallbackFcn(app, @Menu3DHurst_DefaultSelected, true);
            app.Menu3DHurst_Default.Text = 'Default';
            app.Menu3DEntropia = uimenu(app.Funkcie3DMenu);
            app.Menu3DEntropia.MenuSelectedFcn = createCallbackFcn(app, @Menu3DEntropiaSelected, true);
            app.Menu3DEntropia.Text = 'Entropia 3D';
            app.Menu3DEuklidovskaVelkost = uimenu(app.Funkcie3DMenu);
            app.Menu3DEuklidovskaVelkost.MenuSelectedFcn = createCallbackFcn(app, @Menu3DEuklidovskaVelkostSelected, true);
            app.Menu3DEuklidovskaVelkost.Text = 'Euklidovská veľkosť 3D';
            app.Menu3DAutoregresia = uimenu(app.Funkcie3DMenu);
            app.Menu3DAutoregresia.MenuSelectedFcn = createCallbackFcn(app, @Menu3DAutoregresiaSelected, true);
            app.Menu3DAutoregresia.Text = 'Autoreresia 3D';
            app.Menu3DKorelacia = uimenu(app.Funkcie3DMenu);
            app.Menu3DKorelacia.MenuSelectedFcn = createCallbackFcn(app, @Menu3DKorelaciaSelected, true);
            app.Menu3DKorelacia.Text = 'Korelácia 3D';
            app.Menu3DDivergencia = uimenu(app.Funkcie3DMenu);
            app.Menu3DDivergencia.MenuSelectedFcn = createCallbackFcn(app, @Menu3DDivergenciaSelected, true);
            app.Menu3DDivergencia.Text = 'Divergencia 3D';
            app.PredikciaMenu = uimenu(app.UIFigure);
            app.PredikciaMenu.Text = 'Predikčné tunely';
            app.MenuPredikcie = uimenu(app.PredikciaMenu);
            app.MenuPredikcie.MenuSelectedFcn = createCallbackFcn(app, @MenuPredikcieSelected, true);
            app.MenuPredikcie.Text = 'Predikcia';
            app.UIAxes_Kumulativ_Pakety = uiaxes(app.UIFigure);
            title(app.UIAxes_Kumulativ_Pakety, 'Kumulatívne medzery medzi paketmi')
            xlabel(app.UIAxes_Kumulativ_Pakety, 'X')
            ylabel(app.UIAxes_Kumulativ_Pakety, 'Y')
            zlabel(app.UIAxes_Kumulativ_Pakety, 'Z')
            app.UIAxes_Kumulativ_Pakety.ButtonDownFcn = createCallbackFcn(app, @PCAPfileMenuSelected, true);
            app.UIAxes_Te = uiaxes(app.UIFigure);
            title(app.UIAxes_Te, 'Medzery medzi paketmi')
            xlabel(app.UIAxes_Te, 'X')
            ylabel(app.UIAxes_Te, 'Y')
            zlabel(app.UIAxes_Te, 'Z')
            app.UIAxes_KB = uiaxes(app.UIFigure);
            title(app.UIAxes_KB, 'Prírastky informácií v kB')
            xlabel(app.UIAxes_KB, 'X')
            ylabel(app.UIAxes_KB, 'Y')
            zlabel(app.UIAxes_KB, 'Z')
            app.UIAxes_Prirastky = uiaxes(app.UIFigure);
            title(app.UIAxes_Prirastky, 'Prírastky paketov v čase')
            xlabel(app.UIAxes_Prirastky, 'X')
            ylabel(app.UIAxes_Prirastky, 'Y')
            zlabel(app.UIAxes_Prirastky, 'Z')
            app.PriemerLabel = uilabel(app.UIFigure);
            app.PriemerLabel.Text = 'Priemer časov (mikrosekundy):';
            app.tandardnodchlkaLabel = uilabel(app.UIFigure);
            app.tandardnodchlkaLabel.Text = 'Štandardná odchýlka časov:';
            app.PriemerLabel_2 = uilabel(app.UIFigure);
            app.PriemerLabel_2.Text = 'Priemer paketov:';
            app.tandardnodchlkaLabel_3 = uilabel(app.UIFigure);
            app.tandardnodchlkaLabel_3.Text = 'Štandardná odchýlka paketov:';
            app.tandardnodchlkaLabel_2 = uilabel(app.UIFigure);
            app.tandardnodchlkaLabel_2.Text = 'Default veľkosť sample window:';
            app.EditField_ChangeSampleWindow = uieditfield(app.UIFigure, 'numeric');
            app.EditField_ChangeSampleWindow.Limits = [0 Inf];
            app.EditField_ChangeSampleWindow.Value = 1;
            app.EditField_SampleWindow = uieditfield(app.UIFigure, 'text');
            app.EditField_SampleWindow.Placeholder = '0';
            app.EditField_SampleWindow.Value = '3000';
            app.EditField_StandardDeviation_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_StandardDeviation_2.Placeholder = '0';
            app.EditField_StandardDeviation = uieditfield(app.UIFigure, 'text');
            app.EditField_StandardDeviation.Placeholder = '0';
            app.EditField_MeanPackets = uieditfield(app.UIFigure, 'text');
            app.EditField_MeanPackets.Placeholder = '0';
            app.EditField_MeanOfTimes = uieditfield(app.UIFigure, 'text');
            app.EditField_MeanOfTimes.Placeholder = '0';
            app.NovvekospresamplewindowLabel = uilabel(app.UIFigure);
            app.NovvekospresamplewindowLabel.Text = 'Aktuálna veľkosť pre sample window';
            app.VekosnatanchdtLabel = uilabel(app.UIFigure);
            app.VekosnatanchdtLabel.Text = 'Veľkosť načítaných dát:';
            app.EditField_LengthOfData = uieditfield(app.UIFigure, 'text');
            app.EditField_LengthOfData.Placeholder = '0';
            app.EditField_NacitanySubor = uieditfield(app.UIFigure, 'text');
            app.EditField_NacitanySubor.Value = 'žiadny';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Časy'; 'Pakety'};
            app.ButtonUlozFigure_b = uibutton(app.UIFigure, 'push');
            app.ButtonUlozFigure_b.ButtonPushedFcn = createCallbackFcn(app, @ButtonUlozFigure_bPushed, true);
            app.ButtonUlozFigure_b.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.ButtonUlozFigure_b.Tooltip = {'Ulož prírastky informácií v kB ako figure'};
            app.ButtonUlozFigure_b.Text = 'Figure';
            app.EditField_Histogram_b = uieditfield(app.UIFigure, 'numeric');
            app.EditField_Histogram_b.Limits = [0 100];
            app.EditField_Histogram_b.Value = 10;
            app.ButtonZobrazHistogram_b = uibutton(app.UIFigure, 'push');
            app.ButtonZobrazHistogram_b.ButtonPushedFcn = createCallbackFcn(app, @ButtonZobrazHistogram_bPushed, true);
            app.ButtonZobrazHistogram_b.Tooltip = {'Ulož prírastky paketov v čase ako figure'};
            app.ButtonZobrazHistogram_b.Text = 'Zobraz histogram';
            app.EditField_Histogram_a = uieditfield(app.UIFigure, 'numeric');
            app.EditField_Histogram_a.Limits = [0 100];
            app.EditField_Histogram_a.Value = 10;
            app.ButtonZobrazHistogram_a = uibutton(app.UIFigure, 'push');
            app.ButtonZobrazHistogram_a.ButtonPushedFcn = createCallbackFcn(app, @ButtonZobrazHistogram_aPushed, true);
            app.ButtonZobrazHistogram_a.Tooltip = {'Ulož prírastky paketov v čase ako figure'};
            app.ButtonZobrazHistogram_a.Text = 'Zobraz histogram';
            app.ButtonUlozFigure_a = uibutton(app.UIFigure, 'push');
            app.ButtonUlozFigure_a.ButtonPushedFcn = createCallbackFcn(app, @ButtonUlozFigure_aPushed, true);
            app.ButtonUlozFigure_a.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.ButtonUlozFigure_a.Tooltip = {'Ulož prírastky paketov v čase ako figure'};
            app.ButtonUlozFigure_a.Text = 'Figure ';
            app.PrepocitajButton = uibutton(app.UIFigure, 'push');
            app.PrepocitajButton.ButtonPushedFcn = createCallbackFcn(app, @PrepocitajButtonPushed, true);
            app.PrepocitajButton.Text = 'Prepočítať';
            app.Panel_UpravaVstupu = uipanel(app.UIFigure);
            app.Panel_UpravaVstupu.Title = 'Úprava vstupu pre nový IMPORT';
            app.Panel_UpravaVstupu.Visible = 'off';
            app.Panel_UIAxes = uiaxes(app.Panel_UpravaVstupu);
            title(app.Panel_UIAxes, 'Prírastky pre spracovanie')
            xlabel(app.Panel_UIAxes, 'X')
            ylabel(app.Panel_UIAxes, 'Y')
            zlabel(app.Panel_UIAxes, 'Z')
            app.VystrihniButton = uibutton(app.Panel_UpravaVstupu, 'push');
            app.VystrihniButton.ButtonPushedFcn = createCallbackFcn(app, @VystrihniButtonPushed, true);
            app.VystrihniButton.Text = 'Vystrihnúť';
            app.Panel_StrihajOd = uieditfield(app.Panel_UpravaVstupu, 'numeric');
            app.Panel_StrihajOd.Limits = [1 Inf];
            app.Panel_StrihajOd.ValueDisplayFormat = '%.0f';
            app.Panel_StrihajOd.Value = 1;
            app.Panel_dvojbodka = uilabel(app.Panel_UpravaVstupu);
            app.Panel_dvojbodka.Text = ':';
            app.Panel_StrihajPo = uieditfield(app.Panel_UpravaVstupu, 'numeric');
            app.Panel_StrihajPo.Limits = [1 Inf];
            app.Panel_StrihajPo.ValueDisplayFormat = '%.0f';
            app.Panel_StrihajPo.Value = 1000000;
            app.UkonciButton = uibutton(app.Panel_UpravaVstupu, 'push');
            app.UkonciButton.ButtonPushedFcn = createCallbackFcn(app, @UkonciButtonPushed, true);
            app.UkonciButton.Text = 'Ukončiť';
            app.NacitatVstupButton = uibutton(app.Panel_UpravaVstupu, 'push');
            app.NacitatVstupButton.ButtonPushedFcn = createCallbackFcn(app, @NacitatVstupButtonPushed, true);
            app.NacitatVstupButton.Text = 'Načítať vstup';
            app.UlozkBPrirastkyButton = uibutton(app.UIFigure, 'push');
            app.UlozkBPrirastkyButton.ButtonPushedFcn = createCallbackFcn(app, @UlozkBPrirastkyButtonPushed, true);
            app.UlozkBPrirastkyButton.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.UlozkBPrirastkyButton.Tooltip = {'Ulož prírastky informácií v kB ako .mat súbor'};
            app.UlozkBPrirastkyButton.Text = 'Prírastky kB';
            app.UlozPrirastkyCasuButton = uibutton(app.UIFigure, 'push');
            app.UlozPrirastkyCasuButton.ButtonPushedFcn = createCallbackFcn(app, @UlozPrirastkyCasuButtonPushed, true);
            app.UlozPrirastkyCasuButton.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.UlozPrirastkyCasuButton.Tooltip = {'Ulož prírastky paketov v čase ako .mat súbor'};
            app.UlozPrirastkyCasuButton.Text = 'Prírastky času';
            app.NatansborEditFieldLabel = uilabel(app.UIFigure);
            app.NatansborEditFieldLabel.Text = 'Načítaný súbor:';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Resetovať';
            app.UlozPacketLengthButton = uibutton(app.UIFigure, 'push');
            app.UlozPacketLengthButton.ButtonPushedFcn = createCallbackFcn(app, @UlozPacketLengthButtonPushed, true);
            app.UlozPacketLengthButton.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.UlozPacketLengthButton.Tooltip = {'Ulož vstupný vektor veľkostí paketov ako .mat súbor'};
            app.UlozPacketLengthButton.Text = 'Veľkosti paketov';
            app.UlozTimestampButton = uibutton(app.UIFigure, 'push');
            app.UlozTimestampButton.ButtonPushedFcn = createCallbackFcn(app, @UlozTimestampButtonPushed, true);
            app.UlozTimestampButton.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.UlozTimestampButton.Tooltip = {'Ulož vektor príchody časov ako .mat súbor'};
            app.UlozTimestampButton.Text = 'Príchody časov';
            app.Panel_3DFunkcie = uipanel(app.UIFigure);
            app.Panel_3DFunkcie.Title = '3D funkcie';
            app.Panel_3DFunkcie.Visible = 'off';
            app.UIAxes3DFunkcia = uiaxes(app.Panel_3DFunkcie);
            title(app.UIAxes3DFunkcia, 'Title')
            xlabel(app.UIAxes3DFunkcia, 'X')
            ylabel(app.UIAxes3DFunkcia, 'Y')
            zlabel(app.UIAxes3DFunkcia, 'Z')
            app.UIAxes3DViewFunkcia = uiaxes(app.Panel_3DFunkcie);
            title(app.UIAxes3DViewFunkcia, 'Title')
            xlabel(app.UIAxes3DViewFunkcia, 'X')
            ylabel(app.UIAxes3DViewFunkcia, 'Y')
            zlabel(app.UIAxes3DViewFunkcia, 'Z')
            app.Button3DFunkcie_Ukoncit = uibutton(app.Panel_3DFunkcie, 'push');
            app.Button3DFunkcie_Ukoncit.ButtonPushedFcn = createCallbackFcn(app, @Button3DFunkcie_UkoncitPushed, true);
            app.Button3DFunkcie_Ukoncit.Text = 'Ukončiť';
            app.LabelNacitanySuborInfo = uilabel(app.Panel_3DFunkcie);
            app.LabelNacitanySuborInfo.Text = 'Načítaný súbor:';
            app.LabelNacitanySubor = uilabel(app.Panel_3DFunkcie);
            app.LabelNacitanySubor.Text = 'žiadny';
            app.ButtonNacitatVstup3D = uibutton(app.Panel_3DFunkcie, 'push');
            app.ButtonNacitatVstup3D.ButtonPushedFcn = createCallbackFcn(app, @ButtonNacitatVstup3DPushed, true);
            app.ButtonNacitatVstup3D.Text = 'Načítať vstup';
            app.Hodnota1EditFieldLabel = uilabel(app.Panel_3DFunkcie);
            app.Hodnota1EditFieldLabel.Text = 'Hodnota 1';
            app.EditField_Hodnota1 = uieditfield(app.Panel_3DFunkcie, 'numeric');
            app.EditField_Hodnota1.Limits = [0 Inf];
            app.EditField_Hodnota1.Value = 512;
            app.Zadajtehodnotyprevpoetmin1max5Label = uilabel(app.Panel_3DFunkcie);
            app.Zadajtehodnotyprevpoetmin1max5Label.Text = 'Zadajte hodnoty pre výpočet (min 1 - max 5):';
            app.Hodnota2EditFieldLabel = uilabel(app.Panel_3DFunkcie);
            app.Hodnota2EditFieldLabel.Text = 'Hodnota 2';
            app.EditField_Hodnota2 = uieditfield(app.Panel_3DFunkcie, 'numeric');
            app.EditField_Hodnota2.Limits = [0 Inf];
            app.EditField_Hodnota2.Value = 1024;
            app.Hodnota3EditFieldLabel = uilabel(app.Panel_3DFunkcie);
            app.Hodnota3EditFieldLabel.Text = 'Hodnota 3';
            app.EditField_Hodnota3 = uieditfield(app.Panel_3DFunkcie, 'numeric');
            app.EditField_Hodnota3.Limits = [0 Inf];
            app.EditField_Hodnota3.Value = 2048;
            app.Hodnota4EditFieldLabel = uilabel(app.Panel_3DFunkcie);
            app.Hodnota4EditFieldLabel.Text = 'Hodnota 4';
            app.EditField_Hodnota4 = uieditfield(app.Panel_3DFunkcie, 'numeric');
            app.EditField_Hodnota4.Limits = [0 Inf];
            app.EditField_Hodnota4.Value = 4096;
            app.Hodnota5EditFieldLabel = uilabel(app.Panel_3DFunkcie);
            app.Hodnota5EditFieldLabel.Text = 'Hodnota 5';
            app.EditField_Hodnota5 = uieditfield(app.Panel_3DFunkcie, 'numeric');
            app.EditField_Hodnota5.Limits = [0 Inf];
            app.EditField_Hodnota5.Value = 6000;
            app.ButtonVypocitat3D = uibutton(app.Panel_3DFunkcie, 'push');
            app.ButtonVypocitat3D.ButtonPushedFcn = createCallbackFcn(app, @ButtonVypocitat3DPushed, true);
            app.ButtonVypocitat3D.Text = 'Vypočítať';
            app.EditFieldVynasob3D = uieditfield(app.Panel_3DFunkcie, 'numeric');
            app.EditFieldVynasob3D.ValueChangedFcn = createCallbackFcn(app, @EditFieldVynasob3DValueChanged, true);
            app.EditFieldVynasob3D.Value = 2;
            app.ButtonVynasobParameter3D = uibutton(app.Panel_3DFunkcie, 'push');
            app.ButtonVynasobParameter3D.ButtonPushedFcn = createCallbackFcn(app, @ButtonVynasobParameter3DPushed, true);
            app.ButtonVynasobParameter3D.Text = 'Vynásob parameter';
            app.Button3DUloz_FigureData = uibutton(app.Panel_3DFunkcie, 'push');
            app.Button3DUloz_FigureData.ButtonPushedFcn = createCallbackFcn(app, @Button3DUloz_FigureDataPushed, true);
            app.Button3DUloz_FigureData.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.Button3DUloz_FigureData.Text = 'Figure';
            app.Button3DUloz_FigureParameter = uibutton(app.Panel_3DFunkcie, 'push');
            app.Button3DUloz_FigureParameter.ButtonPushedFcn = createCallbackFcn(app, @Button3DUloz_FigureParameterPushed, true);
            app.Button3DUloz_FigureParameter.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.Button3DUloz_FigureParameter.Text = 'Figure';
            app.Button3DFunkcie_Resetovat = uibutton(app.Panel_3DFunkcie, 'push');
            app.Button3DFunkcie_Resetovat.ButtonPushedFcn = createCallbackFcn(app, @Button3DFunkcie_ResetovatPushed, true);
            app.Button3DFunkcie_Resetovat.Text = 'Resetovať';
            app.Panel_KombinovanieMetod = uipanel(app.UIFigure);
            app.Panel_KombinovanieMetod.Title = 'Testovanie viacerých metód';
            app.Panel_KombinovanieMetod.Visible = 'off';
            app.UIAxesKombinovanie = uiaxes(app.Panel_KombinovanieMetod);
            title(app.UIAxesKombinovanie, 'Testovanie matematických metód')
            xlabel(app.UIAxesKombinovanie, 'X')
            ylabel(app.UIAxesKombinovanie, 'Y')
            zlabel(app.UIAxesKombinovanie, 'Z')
            app.UIAxesKombinovanie_Data = uiaxes(app.Panel_KombinovanieMetod);
            title(app.UIAxesKombinovanie_Data, 'Načítané dáta')
            xlabel(app.UIAxesKombinovanie_Data, 'X')
            ylabel(app.UIAxesKombinovanie_Data, 'Y')
            zlabel(app.UIAxesKombinovanie_Data, 'Z')
            app.ButtonKombinovanie_Ukoncit = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Ukoncit.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_UkoncitPushed, true);
            app.ButtonKombinovanie_Ukoncit.Text = 'Ukončiť';
            app.ButtonKombinovanie_NacitatVstup = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_NacitatVstup.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_NacitatVstupPushed, true);
            app.ButtonKombinovanie_NacitatVstup.Text = 'Načítať naslotované dáta';
            app.LabelKombinovanie_Vstup = uilabel(app.Panel_KombinovanieMetod);
            app.LabelKombinovanie_Vstup.Text = 'Načítaný vstup:';
            app.LabelKombinovanie_NacitanySubor = uilabel(app.Panel_KombinovanieMetod);
            app.LabelKombinovanie_NacitanySubor.Text = 'žiadny';
            app.LabelKombinovanie = uilabel(app.Panel_KombinovanieMetod);
            app.LabelKombinovanie.Text = 'Zvoľte metódy pre testovanie:';
            app.ButtonKombinovanie_Vynasob_divergencia = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Vynasob_divergencia.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_Vynasob_divergenciaPushed, true);
            app.ButtonKombinovanie_Vynasob_divergencia.Text = 'Vynásob';
            app.ButtonKombinovanie_Divergencia = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Divergencia.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_DivergenciaPushed, true);
            app.ButtonKombinovanie_Divergencia.Text = 'Divergencia';
            app.ButtonKombinovanie_Vynasob_korelacia = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Vynasob_korelacia.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_Vynasob_korelaciaPushed, true);
            app.ButtonKombinovanie_Vynasob_korelacia.Text = 'Vynásob';
            app.ButtonKombinovanie_Korelacia = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Korelacia.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_KorelaciaPushed, true);
            app.ButtonKombinovanie_Korelacia.Text = 'Korelácia';
            app.ButtonKombinovanie_Vynasob_autoregresia = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Vynasob_autoregresia.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_Vynasob_autoregresiaPushed, true);
            app.ButtonKombinovanie_Vynasob_autoregresia.Text = 'Vynásob';
            app.ButtonKombinovanie_Autoregresia = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Autoregresia.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_AutoregresiaPushed, true);
            app.ButtonKombinovanie_Autoregresia.Text = 'Autoregresia';
            app.ButtonKombinovanie_Vynasob_EuklidovskaVelkost = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Vynasob_EuklidovskaVelkost.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_Vynasob_EuklidovskaVelkostPushed, true);
            app.ButtonKombinovanie_Vynasob_EuklidovskaVelkost.Text = 'Vynásob';
            app.ButtonKombinovanie_EuklidovskaVelkost = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_EuklidovskaVelkost.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_EuklidovskaVelkostPushed, true);
            app.ButtonKombinovanie_EuklidovskaVelkost.Text = 'Eukl. veľkosť';
            app.ButtonKombinovanie_Vynasob_entropia = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Vynasob_entropia.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_Vynasob_entropiaPushed, true);
            app.ButtonKombinovanie_Vynasob_entropia.Text = 'Vynásob';
            app.ButtonKombinovanie_Entropia = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Entropia.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_EntropiaPushed, true);
            app.ButtonKombinovanie_Entropia.Text = 'Entropia';
            app.ButtonKombinovanie_Vynasob_hurst = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Vynasob_hurst.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_Vynasob_hurstPushed, true);
            app.ButtonKombinovanie_Vynasob_hurst.Text = 'Vynásob';
            app.ButtonKombinovanie_Hurst = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Hurst.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_HurstPushed, true);
            app.ButtonKombinovanie_Hurst.Text = 'Hurst';
            app.ButtonKombinovanie_Vynasob_spicatost = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Vynasob_spicatost.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_Vynasob_spicatostPushed, true);
            app.ButtonKombinovanie_Vynasob_spicatost.Text = 'Vynásob';
            app.ButtonKombinovanie_Spicatost = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Spicatost.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_SpicatostPushed, true);
            app.ButtonKombinovanie_Spicatost.Text = 'Špicatosť';
            app.ButtonKombinovanie_Vynasob_sikmost = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Vynasob_sikmost.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_Vynasob_sikmostPushed, true);
            app.ButtonKombinovanie_Vynasob_sikmost.Text = 'Vynásob';
            app.ButtonKombinovanie_Sikmost = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Sikmost.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_SikmostPushed, true);
            app.ButtonKombinovanie_Sikmost.Text = 'Šikmosť';
            app.ButtonKombinovanie_Vynasob_variabilnost = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Vynasob_variabilnost.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_Vynasob_variabilnostPushed, true);
            app.ButtonKombinovanie_Vynasob_variabilnost.Text = 'Vynásob';
            app.ButtonKombinovanie_Variabilnost = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Variabilnost.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_VariabilnostPushed, true);
            app.ButtonKombinovanie_Variabilnost.Text = 'Variabilnosť';
            app.ButtonKombinovanie_Vynasob_odchylka = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Vynasob_odchylka.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_Vynasob_odchylkaPushed, true);
            app.ButtonKombinovanie_Vynasob_odchylka.Text = 'Vynásob';
            app.ButtonKombinovanie_Odchylka = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Odchylka.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_OdchylkaPushed, true);
            app.ButtonKombinovanie_Odchylka.Text = 'Odchýlka';
            app.ButtonKombinovanie_Vynasob_priemer = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Vynasob_priemer.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_Vynasob_priemerPushed, true);
            app.ButtonKombinovanie_Vynasob_priemer.Text = 'Vynásob';
            app.ButtonKombinovanie_Priemer = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Priemer.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_PriemerPushed, true);
            app.ButtonKombinovanie_Priemer.Text = 'Priemer';
            app.ButtonKombinovanie_Resetovat = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_Resetovat.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_ResetovatPushed, true);
            app.ButtonKombinovanie_Resetovat.Text = 'Resetovať';
            app.ButtonKombinovanie_ClearGraf = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_ClearGraf.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_ClearGrafPushed, true);
            app.ButtonKombinovanie_ClearGraf.Text = 'Vyčistiť graf';
            app.ZadajtehodnotuoknaprevpoetEditFieldLabel = uilabel(app.Panel_KombinovanieMetod);
            app.ZadajtehodnotuoknaprevpoetEditFieldLabel.Text = 'Zadajte hodnotu okna pre výpočet:';
            app.EditField_Kombinovanie_okno = uieditfield(app.Panel_KombinovanieMetod, 'numeric');
            app.EditField_Kombinovanie_okno.Limits = [0 Inf];
            app.EditField_Kombinovanie_okno.Value = 512;
            app.VynsobslomEditFieldLabel = uilabel(app.Panel_KombinovanieMetod);
            app.VynsobslomEditFieldLabel.Text = 'Vynásob číslom:';
            app.EditFieldKombinovanie_Vynasob = uieditfield(app.Panel_KombinovanieMetod, 'numeric');
            app.EditFieldKombinovanie_Vynasob.Value = 1;
            app.ButtonKombinovanie_UlozFigure = uibutton(app.Panel_KombinovanieMetod, 'push');
            app.ButtonKombinovanie_UlozFigure.ButtonPushedFcn = createCallbackFcn(app, @ButtonKombinovanie_UlozFigurePushed, true);
            app.ButtonKombinovanie_UlozFigure.Icon = 'icon_download.png';
            app.ButtonKombinovanie_UlozFigure.Text = 'Figure';
            app.Panel_Predikcia = uipanel(app.UIFigure);
            app.Panel_Predikcia.Title = 'Predikčné tunely';
            app.Panel_Predikcia.Visible = 'off';
            app.UIAxes_Predikcia_tunel = uiaxes(app.Panel_Predikcia);
            title(app.UIAxes_Predikcia_tunel, 'Tunel')
            app.UIAxes_Predikcia_predikcia = uiaxes(app.Panel_Predikcia);
            title(app.UIAxes_Predikcia_predikcia, 'Predikcia')
            app.UIAxes_Predikcia_DH = uiaxes(app.Panel_Predikcia);
            title(app.UIAxes_Predikcia_DH, 'Dolná hranica')
            app.UIAxes_Predikcia_HH = uiaxes(app.Panel_Predikcia);
            title(app.UIAxes_Predikcia_HH, 'Horná hranica')
            app.NastavprediknoknoEditFieldLabel = uilabel(app.Panel_Predikcia);
            app.NastavprediknoknoEditFieldLabel.Text = 'Nastav predikčné okno:';
            app.EditFieldPredikcia_NastavOkno = uieditfield(app.Panel_Predikcia, 'numeric');
            app.EditFieldPredikcia_NastavOkno.Limits = [0 Inf];
            app.EditFieldPredikcia_NastavOkno.Value = 100;
            app.ButtonPredikcia_Vypocitat = uibutton(app.Panel_Predikcia, 'push');
            app.ButtonPredikcia_Vypocitat.ButtonPushedFcn = createCallbackFcn(app, @ButtonPredikcia_VypocitatPushed, true);
            app.ButtonPredikcia_Vypocitat.Visible = 'off';
            app.ButtonPredikcia_Vypocitat.Text = 'Vypočítať';
            app.ButtonPredikcia_NacitatVstup = uibutton(app.Panel_Predikcia, 'push');
            app.ButtonPredikcia_NacitatVstup.ButtonPushedFcn = createCallbackFcn(app, @ButtonPredikcia_NacitatVstupPushed, true);
            app.ButtonPredikcia_NacitatVstup.Text = 'Načítaj vstupy';
            app.LabelPredikcia_Vstup = uilabel(app.Panel_Predikcia);
            app.LabelPredikcia_Vstup.Text = 'Načítaný parameter:';
            app.LabelPredikcia_NacitanySubor = uilabel(app.Panel_Predikcia);
            app.LabelPredikcia_NacitanySubor.Text = 'žiadny';
            app.ButtonPredikcia_Ukoncit = uibutton(app.Panel_Predikcia, 'push');
            app.ButtonPredikcia_Ukoncit.ButtonPushedFcn = createCallbackFcn(app, @ButtonPredikcia_UkoncitPushed, true);
            app.ButtonPredikcia_Ukoncit.Text = 'Ukončiť';
            app.CheckBoxPredikcia_Rozsah = uicheckbox(app.Panel_Predikcia);
            app.CheckBoxPredikcia_Rozsah.ValueChangedFcn = createCallbackFcn(app, @CheckBoxPredikcia_RozsahValueChanged, true);
            app.CheckBoxPredikcia_Rozsah.Text = 'Rozsah okolo útoku';
            app.EditFieldPredikcia_Rozsah = uieditfield(app.Panel_Predikcia, 'numeric');
            app.EditFieldPredikcia_Rozsah.Limits = [1 10000];
            app.EditFieldPredikcia_Rozsah.ValueChangedFcn = createCallbackFcn(app, @EditFieldPredikcia_RozsahValueChanged, true);
            app.EditFieldPredikcia_Rozsah.Value = 1000;
            app.LabelPredikcia_Rozpoznanie = uilabel(app.Panel_Predikcia);
            app.LabelPredikcia_Rozpoznanie.Text = 'Doba rozpoznania:';
            app.LabelPredikcia_DlzkaPoplachu = uilabel(app.Panel_Predikcia);
            app.LabelPredikcia_DlzkaPoplachu.Text = 'Dĺžka falošných hlásení:';
            app.LabelPredikcia_PocetPoplachu = uilabel(app.Panel_Predikcia);
            app.LabelPredikcia_PocetPoplachu.Text = 'Počet falošných hlásení:';
            app.LabelPredikcia_RozpoznanieVysledok = uilabel(app.Panel_Predikcia);
            app.LabelPredikcia_RozpoznanieVysledok.Text = '0';
            app.LabelPredikcia_DlzkaVysledok = uilabel(app.Panel_Predikcia);
            app.LabelPredikcia_DlzkaVysledok.Text = '0';
            app.LabelPredikcia_PocetVysledok = uilabel(app.Panel_Predikcia);
            app.LabelPredikcia_PocetVysledok.Text = '0';
            app.Label_Alfa = uilabel(app.Panel_Predikcia);
            app.Label_Alfa.Visible = 'off';
            app.Label_Alfa.Text = 'Alfa';
            app.Label_Beta = uilabel(app.Panel_Predikcia);
            app.Label_Beta.Visible = 'off';
            app.Label_Beta.Text = 'Beta';
            app.Label_B = uilabel(app.Panel_Predikcia);
            app.Label_B.Visible = 'off';
            app.Label_B.Text = 'b';
            app.EditField_Alfa = uieditfield(app.Panel_Predikcia, 'numeric');
            app.EditField_Alfa.Limits = [0 Inf];
            app.EditField_Alfa.Visible = 'off';
            app.EditField_Alfa.Value = 0.05;
            app.EditField_Beta = uieditfield(app.Panel_Predikcia, 'numeric');
            app.EditField_Beta.Limits = [0 Inf];
            app.EditField_Beta.Visible = 'off';
            app.EditField_Beta.Value = 0.05;
            app.EditField_B = uieditfield(app.Panel_Predikcia, 'numeric');
            app.EditField_B.Limits = [0 Inf];
            app.EditField_B.Visible = 'off';
            app.EditField_B.Value = 0.05;
            app.ZvoteparameterLabel = uilabel(app.Panel_Predikcia);
            app.ZvoteparameterLabel.Text = 'Zvoľte tunel:';
            app.DropDownPredikcia_Tunel = uidropdown(app.Panel_Predikcia);
            app.DropDownPredikcia_Tunel.Items = {'Sigma tunel', 'Regresný polynóm', 'DFT', 'AR(n)', 'AR(n,m)'};
            app.DropDownPredikcia_Tunel.ClickedFcn = createCallbackFcn(app, @DropDownPredikcia_TunelClicked, true);
            app.DropDownPredikcia_Tunel.Value = 'Sigma tunel';
            app.ZvotevyhladenieDropDownLabel = uilabel(app.Panel_Predikcia);
            app.ZvotevyhladenieDropDownLabel.Text = 'Zvoľte vyhladenie:';
            app.DropDownPredikcia_Vyhladenie = uidropdown(app.Panel_Predikcia);
            app.DropDownPredikcia_Vyhladenie.Items = {'Žiadne', 'Exponenciálne', 'Holt'};
            app.DropDownPredikcia_Vyhladenie.ClickedFcn = createCallbackFcn(app, @DropDownPredikcia_VyhladenieClicked, true);
            app.DropDownPredikcia_Vyhladenie.Value = 'Žiadne';
            app.ZvotepoetsigiemEditFieldLabel = uilabel(app.Panel_Predikcia);
            app.ZvotepoetsigiemEditFieldLabel.Text = 'Zvoľte počet sigiem:';
            app.EditFieldPredikcia_sigmy = uieditfield(app.Panel_Predikcia, 'numeric');
            app.EditFieldPredikcia_sigmy.Limits = [1 Inf];
            app.EditFieldPredikcia_sigmy.Value = 2;
            app.OdEditFieldLabel = uilabel(app.Panel_Predikcia);
            app.OdEditFieldLabel.Text = 'Od:';
            app.EditFieldPredikcia_Od = uieditfield(app.Panel_Predikcia, 'numeric');
            app.EditFieldPredikcia_Od.Limits = [1 Inf];
            app.EditFieldPredikcia_Od.Value = 1;
            app.DoEditFieldLabel = uilabel(app.Panel_Predikcia);
            app.DoEditFieldLabel.Text = 'Do:';
            app.EditFieldPredikcia_Do = uieditfield(app.Panel_Predikcia, 'numeric');
            app.EditFieldPredikcia_Do.Limits = [1 Inf];
            app.EditFieldPredikcia_Do.Value = 100;
            app.StupepolynmuEditFieldLabel = uilabel(app.Panel_Predikcia);
            app.StupepolynmuEditFieldLabel.Visible = 'off';
            app.StupepolynmuEditFieldLabel.Text = 'Stupeň polynómu:';
            app.EditFieldPredikcia_StupenPolynomu = uieditfield(app.Panel_Predikcia, 'numeric');
            app.EditFieldPredikcia_StupenPolynomu.Limits = [0 Inf];
            app.EditFieldPredikcia_StupenPolynomu.Visible = 'off';
            app.EditFieldPredikcia_StupenPolynomu.Value = 2;
            app.PanelPredikcia_NacitajVstupneData = uipanel(app.Panel_Predikcia);
            app.PanelPredikcia_NacitajVstupneData.Title = 'Načítaj vstup a urči čas útoku';
            app.PanelPredikcia_NacitajVstupneData.Visible = 'off';
            app.UIAxesPanelPredikcia_Parameter = uiaxes(app.PanelPredikcia_NacitajVstupneData);
            title(app.UIAxesPanelPredikcia_Parameter, 'Načítaný parameter')
            zlabel(app.UIAxesPanelPredikcia_Parameter, 'Z')
            app.ButtonPanelPredikcia_Vstup = uibutton(app.PanelPredikcia_NacitajVstupneData, 'push');
            app.ButtonPanelPredikcia_Vstup.ButtonPushedFcn = createCallbackFcn(app, @ButtonPanelPredikcia_VstupPushed, true);
            app.ButtonPanelPredikcia_Vstup.Text = 'Načítaj parameter';
            app.LabelPanelPredikcia_NacitanySubor = uilabel(app.PanelPredikcia_NacitajVstupneData);
            app.LabelPanelPredikcia_NacitanySubor.Text = 'žiadny';
            app.LabelPanelPredikcia_Vstup = uilabel(app.PanelPredikcia_NacitajVstupneData);
            app.LabelPanelPredikcia_Vstup.Text = 'Načítaný parameter:';
            app.ButtonPanelPredikcia_PotvrdCasUtoku = uibutton(app.PanelPredikcia_NacitajVstupneData, 'push');
            app.ButtonPanelPredikcia_PotvrdCasUtoku.ButtonPushedFcn = createCallbackFcn(app, @ButtonPanelPredikcia_PotvrdCasUtokuPushed, true);
            app.ButtonPanelPredikcia_PotvrdCasUtoku.Text = 'Potvrď čas útoku';
            app.ZadajastokuEditFieldLabel = uilabel(app.PanelPredikcia_NacitajVstupneData);
            app.ZadajastokuEditFieldLabel.Text = 'Zadaj čas útoku:';
            app.EditFieldPanelPredikcia_CasUtoku = uieditfield(app.PanelPredikcia_NacitajVstupneData, 'numeric');
            app.EditFieldPanelPredikcia_CasUtoku.Limits = [0 Inf];
            app.Panel_2DFunkcie = uipanel(app.UIFigure);
            app.Panel_2DFunkcie.Title = '2D funkcie';
            app.Panel_2DFunkcie.Visible = 'off';
            app.UIAxes2DFunkcia = uiaxes(app.Panel_2DFunkcie);
            title(app.UIAxes2DFunkcia, 'Title')
            xlabel(app.UIAxes2DFunkcia, 'X')
            ylabel(app.UIAxes2DFunkcia, 'Y')
            zlabel(app.UIAxes2DFunkcia, 'Z')
            app.UIAxes2DFunkciaView = uiaxes(app.Panel_2DFunkcie);
            title(app.UIAxes2DFunkciaView, 'Title')
            xlabel(app.UIAxes2DFunkciaView, 'X')
            ylabel(app.UIAxes2DFunkciaView, 'Y')
            zlabel(app.UIAxes2DFunkciaView, 'Z')
            app.Button2DFunkcie_Ukoncit = uibutton(app.Panel_2DFunkcie, 'push');
            app.Button2DFunkcie_Ukoncit.ButtonPushedFcn = createCallbackFcn(app, @Button2DFunkcie_UkoncitPushed, true);
            app.Button2DFunkcie_Ukoncit.Text = 'Ukončiť';
            app.Label2DNacitanySuborInfo = uilabel(app.Panel_2DFunkcie);
            app.Label2DNacitanySuborInfo.Text = 'Načítaný súbor:';
            app.Label2DNacitanySubor = uilabel(app.Panel_2DFunkcie);
            app.Label2DNacitanySubor.Text = 'žiadny';
            app.ButtonNacitatVstup2D = uibutton(app.Panel_2DFunkcie, 'push');
            app.ButtonNacitatVstup2D.ButtonPushedFcn = createCallbackFcn(app, @ButtonNacitatVstup2DPushed, true);
            app.ButtonNacitatVstup2D.Text = 'Načítať vstup';
            app.HodnotaLabel = uilabel(app.Panel_2DFunkcie);
            app.HodnotaLabel.Text = 'Hodnota';
            app.EditField2D_Hodnota = uieditfield(app.Panel_2DFunkcie, 'numeric');
            app.EditField2D_Hodnota.Limits = [0 Inf];
            app.EditField2D_Hodnota.Value = 512;
            app.LabelHodnotaOkna2D = uilabel(app.Panel_2DFunkcie);
            app.LabelHodnotaOkna2D.Text = 'Zadajte hodnotu okna pre výpočet:';
            app.ButtonVypocitat2D = uibutton(app.Panel_2DFunkcie, 'push');
            app.ButtonVypocitat2D.ButtonPushedFcn = createCallbackFcn(app, @ButtonVypocitat2DPushed, true);
            app.ButtonVypocitat2D.Text = 'Vypočítať';
            app.ButtonVynasobParameter2D = uibutton(app.Panel_2DFunkcie, 'push');
            app.ButtonVynasobParameter2D.ButtonPushedFcn = createCallbackFcn(app, @ButtonVynasobParameter2DPushed, true);
            app.ButtonVynasobParameter2D.Text = 'Vynásob parameter';
            app.ButtonUlozParameter2D = uibutton(app.Panel_2DFunkcie, 'push');
            app.ButtonUlozParameter2D.ButtonPushedFcn = createCallbackFcn(app, @ButtonUlozParameter2DPushed, true);
            app.ButtonUlozParameter2D.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.ButtonUlozParameter2D.Text = 'Parameter';
            app.EditFieldVynasob2D = uieditfield(app.Panel_2DFunkcie, 'numeric');
            app.EditFieldVynasob2D.Value = 2;
            app.Label_PocetRiadkov = uilabel(app.Panel_2DFunkcie);
            app.Label_PocetRiadkov.Visible = 'off';
            app.Label_PocetRiadkov.Text = 'Počet riadkov';
            app.EditField2D_Riadky = uieditfield(app.Panel_2DFunkcie, 'numeric');
            app.EditField2D_Riadky.Limits = [2 Inf];
            app.EditField2D_Riadky.Visible = 'off';
            app.EditField2D_Riadky.Value = 8;
            app.LabelHodnotaMatice = uilabel(app.Panel_2DFunkcie);
            app.LabelHodnotaMatice.Visible = 'off';
            app.LabelHodnotaMatice.Text = 'Zadajte počet riadkov matici:';
            app.LabelHodnota2D_Posun = uilabel(app.Panel_2DFunkcie);
            app.LabelHodnota2D_Posun.Visible = 'off';
            app.LabelHodnota2D_Posun.Text = 'Zadajte hodnotu posunu v okne:';
            app.PosunLabel2D_Posun = uilabel(app.Panel_2DFunkcie);
            app.PosunLabel2D_Posun.Visible = 'off';
            app.PosunLabel2D_Posun.Text = 'Posun';
            app.EditField2D_Posun = uieditfield(app.Panel_2DFunkcie, 'numeric');
            app.EditField2D_Posun.Limits = [1 Inf];
            app.EditField2D_Posun.Visible = 'off';
            app.EditField2D_Posun.Value = 1;
            app.ButtonResetovat2D = uibutton(app.Panel_2DFunkcie, 'push');
            app.ButtonResetovat2D.ButtonPushedFcn = createCallbackFcn(app, @ButtonResetovat2DPushed, true);
            app.ButtonResetovat2D.Text = 'Resetovať';
            app.Button2DUloz_FigureParameter = uibutton(app.Panel_2DFunkcie, 'push');
            app.Button2DUloz_FigureParameter.ButtonPushedFcn = createCallbackFcn(app, @Button2DUloz_FigureParameterPushed, true);
            app.Button2DUloz_FigureParameter.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.Button2DUloz_FigureParameter.Text = 'Figure';
            app.Button2DUloz_FigureData = uibutton(app.Panel_2DFunkcie, 'push');
            app.Button2DUloz_FigureData.ButtonPushedFcn = createCallbackFcn(app, @Button2DUloz_FigureDataPushed, true);
            app.Button2DUloz_FigureData.Icon = fullfile(pathToMLAPP, 'icon_download.png');
            app.Button2DUloz_FigureData.Text = 'Figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end